classdef guiHighBall < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup2                matlab.ui.container.TabGroup
        SendMessageTab           matlab.ui.container.Tab
        XComponentLabel          matlab.ui.control.Label
        AltitudeSetpointLabel    matlab.ui.control.Label
        Altitude_Setpoint        matlab.ui.control.NumericEditField
        AltitudeRangeLabel       matlab.ui.control.Label
        Altitude_Range           matlab.ui.control.NumericEditField
        AltitudeLimitsLabel      matlab.ui.control.Label
        TemperaturePIDLabel      matlab.ui.control.Label
        PorportionalLabel        matlab.ui.control.Label
        Temp_P                   matlab.ui.control.NumericEditField
        IntegralLabel            matlab.ui.control.Label
        Temp_I                   matlab.ui.control.NumericEditField
        DerivativeLabel          matlab.ui.control.Label
        Temp_D                   matlab.ui.control.NumericEditField
        SetpointLabel            matlab.ui.control.Label
        Temp_S                   matlab.ui.control.NumericEditField
        SetModeButtonGroup       matlab.ui.container.ButtonGroup
        ManualButton             matlab.ui.control.ToggleButton
        BasicButton              matlab.ui.control.ToggleButton
        AdvancedButton           matlab.ui.control.ToggleButton
        TabGroup3                matlab.ui.container.TabGroup
        ManualTab                matlab.ui.container.Tab
        AdjustmentValuesLabel    matlab.ui.control.Label
        HeliumVentsLabel         matlab.ui.control.Label
        Helium_Vent              matlab.ui.control.NumericEditField
        BallastDropsLabel        matlab.ui.control.Label
        Ballast_Drop             matlab.ui.control.NumericEditField
        CutdownButton            matlab.ui.control.StateButton
        ManualAdjustmentButton   matlab.ui.control.StateButton
        BasicTab                 matlab.ui.container.Tab
        SelectModeLabel          matlab.ui.control.Label
        VentTimesLabel           matlab.ui.control.Label
        Vent_Time                matlab.ui.control.NumericEditField
        VentIncrementLabel       matlab.ui.control.Label
        Vent_Increment           matlab.ui.control.NumericEditField
        BallastIncrementLabel    matlab.ui.control.Label
        Ballast_Increment        matlab.ui.control.NumericEditField
        AdvancedTab              matlab.ui.container.Tab
        HeliumWeightsLabel       matlab.ui.control.Label
        AlphaLabel               matlab.ui.control.Label
        Helium_Alpha             matlab.ui.control.NumericEditField
        BetaLabel                matlab.ui.control.Label
        Helium_Beta              matlab.ui.control.NumericEditField
        GammaLabel               matlab.ui.control.Label
        Helium_Gamma             matlab.ui.control.NumericEditField
        BallastWeightsLabel      matlab.ui.control.Label
        BetaLabel_2              matlab.ui.control.Label
        Ballast_Beta             matlab.ui.control.NumericEditField
        GammaLabel_2             matlab.ui.control.Label
        Ballast_Gamma            matlab.ui.control.NumericEditField
        AlphaLabel_2             matlab.ui.control.Label
        Ballast_Alpha            matlab.ui.control.NumericEditField
        SendMessageButton        matlab.ui.control.StateButton
        LatitudeLabel_2          matlab.ui.control.Label
        Latitude                 matlab.ui.control.NumericEditField
        PositionLabel_2          matlab.ui.control.Label
        LongitudeLabel_2         matlab.ui.control.Label
        Longitude                matlab.ui.control.NumericEditField
        SecondsLabel             matlab.ui.control.Label
        Update_Rate              matlab.ui.control.NumericEditField
        UpdateRateLabel          matlab.ui.control.Label
        SentMessageListTab       matlab.ui.container.Tab
        UITable                  matlab.ui.control.Table
        ExportDataButton         matlab.ui.control.Button
        ViewRecievedMessagesTab  matlab.ui.container.Tab
        LatitudeLabel            matlab.ui.control.Label
        Latitude_rec             matlab.ui.control.NumericEditField
        LongitudeLabel           matlab.ui.control.Label
        Longitude_rec            matlab.ui.control.NumericEditField
        AltitudeLabel            matlab.ui.control.Label
        Altitude_rec             matlab.ui.control.NumericEditField
        PositionLabel            matlab.ui.control.Label
        ToViewDataSelectTimeReceivedDropDownLabel  matlab.ui.control.Label
        TimeReceivedDropDown     matlab.ui.control.DropDown
        XComponentLabel_2        matlab.ui.control.Label
        Velocity_x               matlab.ui.control.NumericEditField
        YComponentLabel_2        matlab.ui.control.Label
        Velocity_y               matlab.ui.control.NumericEditField
        ZComponentLabel_2        matlab.ui.control.Label
        Velocity_z               matlab.ui.control.NumericEditField
        VelocityLabel            matlab.ui.control.Label
        AccelerationLabel        matlab.ui.control.Label
        XComponentLabel_3        matlab.ui.control.Label
        Acceleration_x           matlab.ui.control.NumericEditField
        YComponentLabel_3        matlab.ui.control.Label
        Acceleration_y           matlab.ui.control.NumericEditField
        ZComponentLabel_3        matlab.ui.control.Label
        Acceleration_z           matlab.ui.control.NumericEditField
        TemperatureLabel         matlab.ui.control.Label
        Temperature_rec          matlab.ui.control.NumericEditField
        PressureLabel            matlab.ui.control.Label
        Pressure                 matlab.ui.control.NumericEditField
        AmbientConditionsLabel   matlab.ui.control.Label
        BatteryPercentageLabel   matlab.ui.control.Label
        Battery                  matlab.ui.control.NumericEditField
        PayloadStatusLabel       matlab.ui.control.Label
        HeliumVentedLabel        matlab.ui.control.Label
        Helium_rec               matlab.ui.control.NumericEditField
        PayloadStatusLabel_2     matlab.ui.control.Label
        Ballast_rec              matlab.ui.control.NumericEditField
        BallastDroppedLabel      matlab.ui.control.Label
        StateEditFieldLabel      matlab.ui.control.Label
        StateEditField           matlab.ui.control.EditField
        EmergencyLampLabel       matlab.ui.control.Label
        EmergencyLamp            matlab.ui.control.Lamp
        ControlEditFieldLabel    matlab.ui.control.Label
        ControlEditField         matlab.ui.control.EditField
        RecievedDataGraphsTab    matlab.ui.container.Tab
        ExportDataButton_2       matlab.ui.control.Button
        DataTypeButtonGroup      matlab.ui.container.ButtonGroup
        AltitudeButton           matlab.ui.control.RadioButton
        LatitudeButton           matlab.ui.control.RadioButton
        TemperatureButton        matlab.ui.control.RadioButton
        PressureButton           matlab.ui.control.RadioButton
        BatteryButton            matlab.ui.control.RadioButton
        BallastDroppedButton     matlab.ui.control.RadioButton
        VertAccButton            matlab.ui.control.RadioButton
        VertVelocityButton       matlab.ui.control.RadioButton
        HeliumVentedButton       matlab.ui.control.RadioButton
        LongitudeButton          matlab.ui.control.RadioButton
        UIAxes                   matlab.ui.control.UIAxes
        AcknowledgeButton        matlab.ui.control.Button
        NoNewMessagesLampLabel   matlab.ui.control.Label
        NoNewMessagesLamp        matlab.ui.control.Lamp
        CheckMessagesButton      matlab.ui.control.Button
    end


properties (Access = private)
        RecD % Queue of received messages with time stamps
        RecT % cell of time strings
        OutM % message to payload
        OutD % data to encode in message
        t
    end
    
    methods (Access = private)
        
        function [Encoded] = decode_message(app, Data)
            Encoded = false(1,240);
            for val = 1:30
                a = (val-1)*8 + 1;
                Encoded(a:a+7) = int2bin(app, Data(val), 8);
            end
            
            temp = datetime('now','TimeZone','local');
            date = datestr(temp, 'dd-mm-yy_HH-MM-ss');
            
            app.RecD.Date = [app.RecD.Date, {date}];
            if app.TimeReceivedDropDown.ItemsData == 0
                app.TimeReceivedDropDown.Items = {date};
                app.TimeReceivedDropDown.ItemsData = 1;
            else
                app.TimeReceivedDropDown.Items = [app.TimeReceivedDropDown.Items, {date}];
            app.TimeReceivedDropDown.ItemsData = [app.TimeReceivedDropDown.ItemsData, app.TimeReceivedDropDown.ItemsData(end) + 1];
            end
                        
            % Temperature Data
            TEMP_MIN = -88;
            TEMP_STEP = .25;
            TEMP_OFFSET = 0;
            TEMP_BITS = 9;
            app.RecD.Temp = [app.RecD.Temp, decode(app, TEMP_MIN, TEMP_STEP, Encoded(TEMP_OFFSET+1:TEMP_OFFSET+TEMP_BITS))];
            
            % Pressure Data
            PRS_MIN = 0;
            PRS_STEP = .25;
            PRS_OFFSET = 9;
            PRS_BITS = 12;
            app.RecD.Prs = [app.RecD.Prs, decode(app, PRS_MIN, PRS_STEP, Encoded(PRS_OFFSET+1:PRS_OFFSET+PRS_BITS))];
            
            % IMU Data
            ACC_MAG_MIN = 0;
            ACC_MAG_STEP = .001;
            ACC_MAG_OFFSET = 21;
            ACC_MAG_BITS = 14;
            AccMag = decode(app, ACC_MAG_MIN, ACC_MAG_STEP, Encoded(ACC_MAG_OFFSET+1:ACC_MAG_OFFSET+ACC_MAG_BITS));
            
            ACC_THETA_MIN = 0;
            ACC_THETA_STEP = 0.0125663706;
            ACC_THETA_OFFSET = 35;
            ACC_THETA_BITS = 10;
            AccTheta = decode(app, ACC_THETA_MIN, ACC_THETA_STEP, Encoded(ACC_THETA_OFFSET+1:ACC_THETA_OFFSET+ACC_THETA_BITS));
            
            ACC_PHI_MIN = 0;
            ACC_PHI_STEP = 0.00628318531;
            ACC_PHI_OFFSET = 45;
            ACC_PHI_BITS = 9;
            AccPhi = decode(app, ACC_PHI_MIN, ACC_PHI_STEP, Encoded(ACC_PHI_OFFSET+1:ACC_PHI_OFFSET+ACC_PHI_BITS));
            
            app.RecD.AccX = [app.RecD.AccX, AccMag*cos(AccPhi)];
            app.RecD.AccY = [app.RecD.AccY, AccMag*sin(AccPhi)];
            app.RecD.AccZ = [app.RecD.AccZ, AccTheta];
            
            % GPS Data
            ALT_MIN = -5000;
            ALT_STEP = 3;
            ALT_OFFSET = 54;
            ALT_BITS = 14;
            app.RecD.Alt = [app.RecD.Alt, decode(app, ALT_MIN, ALT_STEP, Encoded(ALT_OFFSET+1:ALT_OFFSET+ALT_BITS))];
            
            LAT_MIN = -100;
            LAT_STEP = .0001;
            LAT_OFFSET = 68;
            LAT_BITS = 23;
            app.RecD.Lat = [app.RecD.Lat, decode(app, LAT_MIN, LAT_STEP, Encoded(LAT_OFFSET+1:LAT_OFFSET+LAT_BITS))];
            
            LONG_MIN = 18;
            LONG_STEP = .0001;
            LONG_OFFSET = 91;
            LONG_BITS = 23;
            app.RecD.Long = [app.RecD.Long, decode(app, LONG_MIN, LONG_STEP, Encoded(LONG_OFFSET+1:LONG_OFFSET+LONG_BITS))];
            
            % Velovity Data
            VEL_MAG_MIN = 0;
            VEL_MAG_STEP = 0.001;
            VEL_MAG_OFFSET = 114;
            VEL_MAG_BITS = 16;
            VelMag = decode(app, VEL_MAG_MIN, VEL_MAG_STEP, Encoded(VEL_MAG_OFFSET+1:VEL_MAG_OFFSET+VEL_MAG_BITS));
            
            VEL_THETA_MIN = 0;
            VEL_THETA_STEP = 0.0125663706;
            VEL_THETA_OFFSET = 130;
            VEL_THETA_BITS = 10;
            VelTheta = decode(app, VEL_THETA_MIN, VEL_THETA_STEP, Encoded(VEL_THETA_OFFSET+1:VEL_THETA_OFFSET+VEL_THETA_BITS));
            
            VEL_PHI_MIN = 0;
            VEL_PHI_STEP = 0.00628318531;
            VEL_PHI_OFFSET = 140;
            VEL_PHI_BITS = 9;
            VelPhi = decode(app, VEL_PHI_MIN, VEL_PHI_STEP, Encoded(VEL_PHI_OFFSET+1:VEL_PHI_OFFSET+VEL_PHI_BITS));
            
            app.RecD.VelX = [app.RecD.VelX, VelMag*cos(VelPhi)];
            app.RecD.VelY = [app.RecD.VelY, VelMag*sin(VelPhi)];
            app.RecD.VelZ = [app.RecD.VelZ, VelTheta];
            
            % Helium Data
            HEL_MIN = 0;
            HEL_STEP = 1;
            HEL_OFFSET = 149;
            HEL_BITS = 10;
            app.RecD.Hel = [app.RecD.Hel, decode(app, HEL_MIN, HEL_STEP, Encoded(HEL_OFFSET+1:HEL_OFFSET+HEL_BITS))];
            
            % Ballast Data
            BALL_MIN = 0;
            BALL_STEP = 1;
            BALL_OFFSET = 159;
            BALL_BITS = 10;
            app.RecD.Bal = [app.RecD.Bal, decode(app, BALL_MIN, BALL_STEP, Encoded(BALL_OFFSET+1:BALL_OFFSET+BALL_BITS))];
            
            % Battery Data
            BATT_MIN = 0;
            BATT_STEP = .1;
            BATT_OFFSET = 169;
            BATT_BITS = 10;
            app.RecD.Batt = [app.RecD.Batt, decode(app, BATT_MIN, BATT_STEP, Encoded(BATT_OFFSET+1:BATT_OFFSET+BATT_BITS))];
            
            % Time Data
            TIME_MIN = 0;
            TIME_STEP = 1;
            TIME_OFFSET = 179;
            TIME_BITS = 17;
            app.RecT = [app.RecT, decode(app, TIME_MIN, TIME_STEP, Encoded(TIME_OFFSET+1:TIME_OFFSET+TIME_BITS))];
            
            % State Data
            STATE_OFFSET = 196;
            STATE_BITS = 3;
            app.RecD.State = [app.RecD.State, decode(app, 0, 1, Encoded(STATE_OFFSET+1:STATE_OFFSET+STATE_BITS))];
            
            % Control Mode Data
            CRTL_OFFSET = 199;
            CRTL_BITS = 2;
            app.RecD.Ctrl = [app.RecD.Ctrl, decode(app, 0, 1, Encoded(CRTL_OFFSET+1:CRTL_OFFSET+CRTL_BITS))];
            
            % Emergency Data
            EMRG_OFFSET = 201;
            EMRG_BITS = 2;
            app.RecD.Emrg = [app.RecD.Emrg, decode(app, 0, 1, Encoded(EMRG_OFFSET+1:EMRG_OFFSET+EMRG_BITS))];
   
        end
        
        function value = decode(app, min, step, bin)
            input = log2dec(app, bin);
            value = (input*step)+min;
        end
    
        function [dec] = log2dec(app, bin)
            long = length(bin);
            swap = long:-1:1;
            dec = 0;
            for i = 1:long
                if bin(swap(i))
                    dec = dec + 2^(i-1);
                end
            end
        end
    
        function [bin] = int2bin(app, integer, long)
            integer = round(integer);
            if integer == 0
                bin = zeros(1,long);
            elseif integer < 0
                bin = zeros(1,long);
                f = uifigure;
                message = sprintf('Value below expected minimum! \n Consider changing input.');
                uialert(f,message,'Warning',...
                    'Icon','warning');
            else
                l = floor(log2(integer));
                
                integer = integer - 2^l;
                bin = true(1,l+1);
                for val = l-1:-1:0
                    if (integer < 2^val)
                        bin(l+1-val) = false;
                    else
                        integer = integer - 2^val;
                    end
                end
                if length(bin) ~= long
                    start = false(1,(long-l-1));
                    bin = [start, bin];
                end
            end
        end
        
        function [message] = encode_message(app)
            message = false(1,240);
            
            %altitude
            ALT_MIN = 0;
            ALT_STEP = 5;
            ALT_OFFSET = 0;
            ALT_LENGTH = 13;
            ALT_VALUE = (app.OutD.Alt - ALT_MIN)/ALT_STEP;
            message(1+ALT_OFFSET:ALT_OFFSET+ALT_LENGTH) = int2bin(app, ALT_VALUE, ALT_LENGTH);
            
            % altitude_buffer
            ALT_BUF_MIN = 0;
            ALT_BUF_STEP = 5;
            ALT_BUF_OFFSET = 13;
            ALT_BUF_LENGTH = 10;
            ALT_BUF_VALUE = (app.OutD.AltBuf- ALT_BUF_MIN)/ALT_BUF_STEP;
            message(1+ALT_BUF_OFFSET:ALT_BUF_OFFSET+ALT_BUF_LENGTH) = int2bin(app, ALT_BUF_VALUE, ALT_BUF_LENGTH);
            
            % lat_degree
            LAT_MIN = 18;
            LAT_STEP = .01;
            LAT_OFFSET = 23;
            LAT_LENGTH = 12;
            LAT_VALUE = (app.OutD.Lat- LAT_MIN)/LAT_STEP;
            message(1+LAT_OFFSET:LAT_OFFSET+LAT_LENGTH) = int2bin(app, LAT_VALUE, LAT_LENGTH);
            
            % long_deg
            LONG_MIN = -100;
            LONG_STEP = .01;
            LONG_OFFSET = 46;
            LONG_LENGTH = 12;
            LONG_VALUE = (app.OutD.Long - LONG_MIN)/LONG_STEP;
            message(1+LONG_OFFSET:LONG_OFFSET+LONG_LENGTH) = int2bin(app, LONG_VALUE, LONG_LENGTH);

            % cutdown
            CUT_MIN = 0;
            CUT_STEP = 1;
            CUT_OFFSET = 69;
            CUT_LENGTH = 2;
            CUT_VALUE = (app.OutD.Cut - CUT_MIN)/CUT_STEP;
            message(1+CUT_OFFSET:CUT_OFFSET+CUT_LENGTH) = int2bin(app, CUT_VALUE, CUT_LENGTH);
            
            % update_rate
            UPD_MIN = 0;
            UPD_STEP = 5;
            UPD_OFFSET = 71;
            UPD_LENGTH = 9;
            UPD_VALUE = (app.OutD.Upd - UPD_MIN)/UPD_STEP;
            message(1+UPD_OFFSET:UPD_OFFSET+UPD_LENGTH) = int2bin(app, UPD_VALUE, UPD_LENGTH);
            
            % hel_alpha
            HEL_A_MIN = 0;
            HEL_A_STEP = .01;
            HEL_A_OFFSET = 80;
            HEL_A_LENGTH = 10;
            HEL_A_VALUE = (app.OutD.HelA - HEL_A_MIN)/HEL_A_STEP;
            message(1+HEL_A_OFFSET:HEL_A_OFFSET+HEL_A_LENGTH) = int2bin(app, HEL_A_VALUE, HEL_A_LENGTH);
            
            % hel_beta
            HEL_B_MIN = 0;
            HEL_B_STEP = .01;
            HEL_B_OFFSET = 90;
            HEL_B_LENGTH = 10;
            HEL_B_VALUE = (app.OutD.HelB - HEL_B_MIN)/HEL_B_STEP;
            message(1+HEL_B_OFFSET:HEL_B_OFFSET+HEL_B_LENGTH) = int2bin(app, HEL_B_VALUE, HEL_B_LENGTH);
            
            % hel_gamma
            HEL_G_MIN = 0;
            HEL_G_STEP = .01;
            HEL_G_OFFSET = 100;
            HEL_G_LENGTH = 10;
            HEL_G_VALUE = (app.OutD.HelG - HEL_G_MIN)/HEL_G_STEP;
            message(1+HEL_G_OFFSET:HEL_G_OFFSET+HEL_G_LENGTH) = int2bin(app, HEL_G_VALUE, HEL_G_LENGTH);
            
            % bal_alpha
            BAL_A_MIN = 0;
            BAL_A_STEP = .01;
            BAL_A_OFFSET = 110;
            BAL_A_LENGTH = 10;
            BAL_A_VALUE = (app.OutD.BalA - BAL_A_MIN)/BAL_A_STEP;
            message(1+BAL_A_OFFSET:BAL_A_OFFSET+BAL_A_LENGTH) = int2bin(app, BAL_A_VALUE, BAL_A_LENGTH);
            
            % bal_beta
            BAL_B_MIN = 0;
            BAL_B_STEP = .01;
            BAL_B_OFFSET = 120;
            BAL_B_LENGTH = 10;
            BAL_B_VALUE = (app.OutD.BalB - BAL_B_MIN)/BAL_B_STEP;
            message(1+BAL_B_OFFSET:BAL_B_OFFSET+BAL_B_LENGTH) = int2bin(app, BAL_B_VALUE, BAL_B_LENGTH);
            
            % bal_gamma
            BAL_G_MIN = 0;
            BAL_G_STEP = .01;
            BAL_G_OFFSET = 130;
            BAL_G_LENGTH = 10;
            BAL_G_VALUE = (app.OutD.BalG - BAL_G_MIN)/BAL_G_STEP;
            message(1+BAL_G_OFFSET:BAL_G_OFFSET+BAL_G_LENGTH) = int2bin(app, BAL_G_VALUE, BAL_G_LENGTH);
            
            % length_vent
            VENT_TIME_MIN = 0;
            VENT_TIME_STEP = .1;
            VENT_TIME_OFFSET = 140;
            VENT_TIME_LENGTH = 10;
            VENT_TIME_VALUE = (app.OutD.VentTime - VENT_TIME_MIN)/VENT_TIME_STEP;
            message(1+VENT_TIME_OFFSET:VENT_TIME_OFFSET+VENT_TIME_LENGTH) = int2bin(app, VENT_TIME_VALUE, VENT_TIME_LENGTH);
            
            % vent_inc
            VENT_INC_MIN = 0;
            VENT_INC_STEP = 1;
            VENT_INC_OFFSET = 150;
            VENT_INC_LENGTH = 7;
            VENT_INC_VALUE = (app.OutD.VentInc - VENT_INC_MIN)/VENT_INC_STEP;
            message(1+VENT_INC_OFFSET:VENT_INC_OFFSET+VENT_INC_LENGTH) = int2bin(app, VENT_INC_VALUE, VENT_INC_LENGTH);
            
            % ballast_inc
            BAL_INC_MIN = 0;
            BAL_INC_STEP = 1;
            BAL_INC_OFFSET = 157;
            BAL_INC_LENGTH = 7;
            BAL_INC_VALUE = (app.OutD.BalInc - BAL_INC_MIN)/BAL_INC_STEP;
            message(1+BAL_INC_OFFSET:BAL_INC_OFFSET+BAL_INC_LENGTH) = int2bin(app, BAL_INC_VALUE, BAL_INC_LENGTH);
            
            % temp_setpoint
            TEMP_S_MIN = -70;
            TEMP_S_STEP = .5;
            TEMP_S_OFFSET = 164;
            TEMP_S_LENGTH = 7;
            TEMP_S_VALUE = (app.OutD.TempS - TEMP_S_MIN)/TEMP_S_STEP;
            message(1+TEMP_S_OFFSET:TEMP_S_OFFSET+TEMP_S_LENGTH) = int2bin(app, TEMP_S_VALUE, TEMP_S_LENGTH);
            
            % temp_P
            TEMP_P_MIN = 0;
            TEMP_P_STEP = .001;
            TEMP_P_OFFSET = 171;
            TEMP_P_LENGTH = 14;
            TEMP_P_VALUE = (app.OutD.TempP - TEMP_P_MIN)/TEMP_P_STEP;
            message(1+TEMP_P_OFFSET:TEMP_P_OFFSET+TEMP_P_LENGTH) = int2bin(app, TEMP_P_VALUE, TEMP_P_LENGTH);
            
            % temp_I
            TEMP_I_MIN = 0;
            TEMP_I_STEP = .001;
            TEMP_I_OFFSET = 185;
            TEMP_I_LENGTH = 14;
            TEMP_I_VALUE = (app.OutD.TempI - TEMP_I_MIN)/TEMP_I_STEP;
            message(1+TEMP_I_OFFSET:TEMP_I_OFFSET+TEMP_I_LENGTH) = int2bin(app, TEMP_I_VALUE, TEMP_I_LENGTH);
            
            % temp_D
            TEMP_D_MIN = 0;
            TEMP_D_STEP = .001;
            TEMP_D_OFFSET = 199;
            TEMP_D_LENGTH = 14;
            TEMP_D_VALUE = (app.OutD.TempD - TEMP_D_MIN)/TEMP_D_STEP;
            message(1+TEMP_D_OFFSET:TEMP_D_OFFSET+TEMP_D_LENGTH) = int2bin(app, TEMP_D_VALUE, TEMP_D_LENGTH);
            
            % control_mode
            CTRL_MIN = 0;
            CTRL_STEP = 1;
            CTRL_OFFSET = 213;
            CTRL_LENGTH = 2;
            CTRL_VALUE = (app.OutD.Ctrl - CTRL_MIN)/CTRL_STEP;
            message(1+CTRL_OFFSET:CTRL_OFFSET+CTRL_LENGTH) = int2bin(app, CTRL_VALUE, CTRL_LENGTH);
            
            % manual_adjust
            MAN_ADJ_OFFSET = 215;
            message(1+MAN_ADJ_OFFSET) = app.OutD.ManAdj;
            
            % manual_select
            MAN_SEL_OFFSET = 216;
            message(1+MAN_SEL_OFFSET) = app.OutD.ManSel;
            
            % manual_amount
            MAN_AMT_MIN = 0;
            MAN_AMT_STEP = 1;
            MAN_AMT_OFFSET = 217;
            MAN_AMT_LENGTH = 10;
            MAN_AMT_VALUE = (app.OutD.ManAmt - MAN_AMT_MIN)/MAN_AMT_STEP;
            message(1+MAN_AMT_OFFSET:MAN_AMT_OFFSET+MAN_AMT_LENGTH) = int2bin(app, MAN_AMT_VALUE, MAN_AMT_LENGTH);
            
        end
        
        function checkMessages(app)
            
            % Read Unread Emails in the folder
            mails = ReadOutlook(...
                'Folder', 'Senior Design SBD',...
                'Savepath', 'C:\Users\Kyle\Documents\Classes\Senior Design\Test Comm\Incoming',...
                'Read',1 , ...
                'Mark',1);
            
            % Read data from incoming file and move it to another folder
            cd Incoming
            list = dir( '*data*.sbd');
            cd ..
            num = length(list);
            
            if num > 0
                if num == 1
                    app.NoNewMessagesLampLabel.Text = '1 New Message';
                else
                    app.NoNewMessagesLampLabel.Text = [num2str(num), ' New Messages'];
                end
                app.NoNewMessagesLamp.Color = 'green';
                temp = datetime('now','TimeZone','local');
                date = datestr(temp, 'yy-mm-dd_HH-MM-ss');
            else
                temp = datetime('now','TimeZone','local');
                date = datestr(temp, 'yy-mm-dd_HH-MM-ss');
                disp(['Run CheckMessage @ ', date])
            end
            
            while(num > 0)
                name = ['Incoming/',list(num).name;];
                decode_message(app, name);
                movefile(name, 'Read', 'f');
                num = num - 1;
            end
        end
        
        function SendMessage(app)
            bin = app.OutM;
            
            % Write data to binary .sbd filed
            temp = datetime('now','TimeZone','local');
            date = datestr(temp, 'yy-mm-dd_HH-MM-ss');
            file_name = [date,'data.sbd'];
            FID = fopen(file_name, 'w+');
            fwrite(FID, bin, 'ubit1');
            fclose('all');
            
            % Send email with sbd Attachment
            
            % Define these variables appropriately:
            mail = 'teamHighBall@yahoo.com'; %Your Yahoo email address
            password = 'MITLLHighBall2018';  %Your Yahoo password
            setpref('Internet','SMTP_Server','smtp.mail.yahoo.com');
            setpref('Internet','E_mail',mail);
            setpref('Internet','SMTP_Username',mail);
            setpref('Internet','SMTP_Password',password);
            props = java.lang.System.getProperties;
            props.setProperty('mail.smtp.auth','true');
            props.setProperty('mail.smtp.socketFactory.class', 'javax.net.ssl.SSLSocketFactory');
            props.setProperty('mail.smtp.socketFactory.port','465');
            
            % to_mail =  'data@sbd.iridium.com'; % use this email for messages
            to_mail = 'kwatters6@gatech.edu'; % change to my email for testing
            % ISU = '300434062690230'; % ISU number here
            ISU = 'TestNumber';
            
            sendmail(to_mail, ISU, 'Message is Unrelated', file_name)
            
            % Move sent data to another folder and record data in queue
            movefile([date,'data.sbd'], 'Sent');
            app.UITable.Data = struct2cell(app.OutD)';
            app.OutM = [];
        end
        
        function confirm_rem(app,src,event)
            display(event.SelectedOption);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RecT = [];  % Queue of reccieved messages with time stamps
            app.RecD = struct;
            app.OutM = []; % message to payload
            app.OutD = struct; % data to encode in message
            app.TimeReceivedDropDown.ItemsData = 0;
            
            app.OutD.Alt = [];
            app.OutD.AltBuf = [];
            app.OutD.Lat = [];
            app.OutD.Long = [];
            app.OutD.Cut = [];
            app.OutD.Upd = [];
            app.OutD.HelA = [];
            app.OutD.HelB = [];
            app.OutD.HelG = [];
            app.OutD.BalA = [];
            app.OutD.BalB = [];
            app.OutD.BalG = [];
            app.OutD.VentTime = [];
            app.OutD.VentInc = [];
            app.OutD.BalInc = [];
            app.OutD.TempS = [];
            app.OutD.TempP = [];
            app.OutD.TempI = [];
            app.OutD.TempD = [];
            app.OutD.Ctrl = [];
            app.OutD.ManAdj = [];
            app.OutD.ManSel = [];
            app.OutD.ManAmt = [];
            
            app.RecD.Alt = [];
            app.RecD.Lat = [];
            app.RecD.Long = [];
            app.RecD.VelX = [];
            app.RecD.VelY = [];
            app.RecD.VelZ = [];
            app.RecD.AccX = [];
            app.RecD.AccY = [];
            app.RecD.AccZ = [];
            app.RecD.Temp = [];
            app.RecD.Prs = [];
            app.RecD.Batt = [];
            app.RecD.Bal = [];
            app.RecD.Hel = [];
            app.RecD.Ctrl = [];
            app.RecD.State = [];
            app.RecD.Emrg = [];
            app.RecD.Date = [];

            % app.t = timer('ExecutionMode','fixedSpacing', ...
%                 'Name', 'CheckMailTimer', ...
%                 'TasksToExecute', Inf, ...
%                 'Period', 30);
%             app.t.TimerFcn =  @(~, ~) app.checkMessages;
%             start(app.t);
        end

        % Selection changed function: DataTypeButtonGroup
        function DataTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataTypeButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Altitude'
                    plot(app.UIAxes, app.RecT, app.RecD.Alt);
                    app.UIAxes.Title.String = 'Altitude';
                    app.UIAxes.XLabel.String = 'Time Elapsed';
                    app.UIAxes.YLabel.String = 'm';
                case 'Latitude'
                    plot(app.UIAxes, app.RecT, app.RecD.Lat);
                    app.UIAxes.Title.String = 'Latitude';
                    app.UIAxes.XLabel.String = 'Time Elapsed';
                    app.UIAxes.YLabel.String = '';
                case 'Longitude'
                    plot(app.UIAxes, app.RecT, app.RecD.Long);
                    app.UIAxes.Title.String = 'Longitude';
                    app.UIAxes.XLabel.String = 'Time Elapsed';
                    app.UIAxes.YLabel.String = '';
                case 'Temperature'
                    plot(app.UIAxes, app.RecT, app.RecD.Temp);
                    app.UIAxes.Title.String = 'Temperature';
                    app.UIAxes.XLabel.String = 'Time Elapsed';
                    app.UIAxes.YLabel.String = 'C';
                case 'Pressure'
                    plot(app.UIAxes, app.RecT, app.RecD.Prs);
                    app.UIAxes.Title.String = 'Pressure';
                    app.UIAxes.XLabel.String = 'Time Elapsed';
                    app.UIAxes.YLabel.String = 'hPa';
                case 'Battery'
                    plot(app.UIAxes, app.RecT, app.RecD.Batt);
                    app.UIAxes.Title.String = 'Percent Battery Remaining';
                    app.UIAxes.XLabel.String = 'Time Elapsed';
                    app.UIAxes.YLabel.String = '%';
                case 'Ballast Dropped'
                    plot(app.UIAxes, app.RecT, app.RecD.Bal);
                    app.UIAxes.Title.String = 'Ballast Used';
                    app.UIAxes.XLabel.String = 'Time Elapsed';
                    app.UIAxes.YLabel.String = '%';
                case 'Helium Vented'
                    plot(app.UIAxes, app.RecT, app.RecD.Bal);
                    app.UIAxes.Title.String = 'Helium Used';
                    app.UIAxes.XLabel.String = 'Time Elapsed';
                    app.UIAxes.YLabel.String = '%';
                case 'Vert. Acc'
                    plot(app.UIAxes, app.RecT, app.RecD.AccZ);
                    app.UIAxes.Title.String = 'Vertical Acceleration';
                    app.UIAxes.XLabel.String = 'Time Elapsed';
                    app.UIAxes.YLabel.String = 'm/s^2';
                case 'Vert. Velocity'
                    plot(app.UIAxes, app.RecT, app.RecD.VelZ);
                    app.UIAxes.Title.String = 'Vertical Velocity';
                    app.UIAxes.XLabel.String = 'Time Elapsed';
                    app.UIAxes.YLabel.String = 'm/s';
            end
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            temp = datetime('now','TimeZone','local');
            date = datestr(temp, 'yy-mm-dd_HH-MM-ss');
            filename = ['Sent_Messages_', date, '.xlsx'];
            xlswrite(filename, app.UITable.Data);
        end

        % Button pushed function: ExportDataButton_2
        function ExportDataButton_2Pushed(app, event)
            temp = datetime('now','TimeZone','local');
            date = datestr(temp, 'yy-mm-dd_HH-MM-ss');
            filename = ['Recieved_Data_', date, '.xlsx'];
            Data_Cell = struct2cell(app.RecD);
            Data_Cell = [Data_Cell; {app.RecT}];
            xlswrite(filename, Data_Cell);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: AcknowledgeButton
        function AcknowledgeButtonPushed(app, event)
            app.NoNewMessagesLamp.Color = 'black';
            app.NoNewMessagesLampLabel.Text = 'No New Messages';
        end

        % Value changed function: TimeReceivedDropDown
        function TimeReceivedDropDownValueChanged(app, event)
            value = app.TimeReceivedDropDown.Value;
            
            app.Altitude_rec.Value = app.RecD.Alt(value);
            app.Latitude_rec.Value = app.RecD.Lat(value);
            app.Longitude_rec.Value = app.RecD.Long(value);
            app.Velocity_x.Value = app.RecD.VelX(value);
            app.Velocity_y.Value = app.RecD.VelY(value);
            app.Velocity_z.Value = app.RecD.VelZ(value);
            app.Acceleration_x.Value = app.RecD.AccX(value);
            app.Acceleration_y.Value = app.RecD.AccY(value);
            app.Acceleration_z.Value = app.RecD.AccZ(value);
            app.Temperature_rec.Value = app.RecD.Temp(value);
            app.Pressure.Value = app.RecD.Prs(value);
            app.Battery.Value = app.RecD.Batt(value);
            app.Ballast_rec.Value = app.RecD.Bal(value);
            app.Helium_rec.Value = app.RecD.Hel(value);
            if app.RecD.Ctrl(value) == 1
                app.ControlEditField.Value = 'Manual';
            elseif app.RecD.Ctrl(value) == 2
                app.ControlEditField.Value = 'Basic';
            elseif app.RecD.Ctrl(value) == 3
                app.ControlEditField.Value = 'Advanced';
            else
                app.ControlEditField.Value = 'Error';
            end
            
            if app.RecD.State(value) == 1
                app.StateEditField.Value = 'Pre-Launch';
            elseif app.RecD.State(value) == 2
                app.StateEditField.Value = 'Launch';
            elseif app.RecD.State(value) == 3
                app.StateEditField.Value = 'Falling';
            elseif app.RecD.State(value) == 4
                app.StateEditField.Value = 'Recovery';
            elseif app.RecD.State(value) == 5
                app.StateEditField.Value = 'Testing';
            else
                app.StateEditField.Value = 'Error';
            end
            
            if app.RecD.Emrg(value) == 3
                app.EmergencyLamp.Color = 'red';
            elseif app.RecD.Emrg(value) == 0
                app.EmergencyLamp.Color = 'green';
            else
                app.EmergencyLamp.Color = 'black';
            end
        end

        % Value changed function: SendMessageButton
        function SendMessageButtonValueChanged(app, event)
            % value = app.SendMessageButton.Value;
            app.OutD.Alt = [app.OutD.Alt, app.Altitude_Setpoint.Value];
            app.OutD.AltBuf = [app.OutD.AltBuf, app.Altitude_Range.Value];
            app.OutD.Lat = [app.OutD.Lat, app.Latitude.Value];
            app.OutD.Long = [app.OutD.Long, app.Longitude.Value];
            app.OutD.Cut = [app.OutD.Cut, app.CutdownButton.Value];
            app.OutD.Upd = [app.OutD.Upd, app.Update_Rate.Value];
            app.OutD.HelA = [app.OutD.HelA, app.Helium_Alpha.Value];
            app.OutD.HelB = [app.OutD.HelB, app.Helium_Beta.Value];
            app.OutD.HelG = [app.OutD.HelG, app.Helium_Gamma.Value];
            app.OutD.BalA = [app.OutD.BalA, app.Ballast_Alpha.Value];
            app.OutD.BalB = [app.OutD.BalB, app.Ballast_Beta.Value];
            app.OutD.BalG = [app.OutD.BalG, app.Ballast_Gamma.Value];
            app.OutD.VentTime = [app.OutD.VentTime, app.Vent_Time.Value];
            app.OutD.VentInc = [app.OutD.VentInc, app.Vent_Increment.Value];
            app.OutD.BalInc = [app.OutD.BalInc, app.Ballast_Increment.Value];
            app.OutD.TempS = [app.OutD.TempS, app.Temp_S.Value];
            app.OutD.TempP = [app.OutD.TempP, app.Temp_P.Value];
            app.OutD.TempI = [app.OutD.TempI, app.Temp_I.Value];
            app.OutD.TempD = [app.OutD.TempD, app.Temp_D.Value];
            if app.ManualButton.Value == true
                app.OutD.Ctrl = [app.OutD.Ctrl, 1];
            elseif app.BasicButton.Value == true
                app.OutD.Ctrl = [app.OutD.Ctrl, 2];
            else
                app.OutD.Ctrl = [app.OutD.Ctrl, 3];
            end
            app.OutD.ManAdj = [app.OutD.ManAdj, app.ManualAdjustmentButton.Value];
            if app.Helium_Vent.Value ~= 0
                app.OutD.ManSel = [app.OutD.ManSel, 0];
                app.OutD.ManAmt = [app.OutD.ManAmt, app.Helium_Vent.Value];
            else
                app.OutD.ManSel = [app.OutD.ManSel, 1];
                app.OutD.ManAmt = [app.OutD.ManAmt, app.Ballast_Drop.Value];
            end
                
            app.OutM = encode_message(app);
            SendMessage(app);
            app.SendMessageButton.Value = false;
        end

        % Button pushed function: CheckMessagesButton
        function CheckMessagesButtonPushed(app, event)
            checkMessages(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.SendMessageTab = uitab(app.TabGroup2);
            app.SendMessageTab.Title = 'Send Message';
            app.XComponentLabel = uilabel(app.SendMessageTab);
            app.XComponentLabel.Text = 'X Component';
            app.AltitudeSetpointLabel = uilabel(app.SendMessageTab);
            app.AltitudeSetpointLabel.Text = 'Altitude Setpoint';
            app.Altitude_Setpoint = uieditfield(app.SendMessageTab, 'numeric');
            app.Altitude_Setpoint.Limits = [0 40955];
            app.Altitude_Setpoint.Editable = 'off';
            app.Altitude_Setpoint.Value = 20000;
            app.AltitudeRangeLabel = uilabel(app.SendMessageTab);
            app.AltitudeRangeLabel.Text = 'Altitude Range';
            app.Altitude_Range = uieditfield(app.SendMessageTab, 'numeric');
            app.Altitude_Range.Limits = [0 5115];
            app.Altitude_Range.Editable = 'off';
            app.Altitude_Range.Value = 2000;
            app.AltitudeLimitsLabel = uilabel(app.SendMessageTab);
            app.AltitudeLimitsLabel.Text = 'Altitude Limits';
            app.TemperaturePIDLabel = uilabel(app.SendMessageTab);
            app.TemperaturePIDLabel.Text = 'Temperature PID';
            app.PorportionalLabel = uilabel(app.SendMessageTab);
            app.PorportionalLabel.Text = 'Porportional';
            app.Temp_P = uieditfield(app.SendMessageTab, 'numeric');
            app.Temp_P.Editable = 'off';
            app.IntegralLabel = uilabel(app.SendMessageTab);
            app.IntegralLabel.Text = 'Integral';
            app.Temp_I = uieditfield(app.SendMessageTab, 'numeric');
            app.Temp_I.Editable = 'off';
            app.DerivativeLabel = uilabel(app.SendMessageTab);
            app.DerivativeLabel.Text = 'Derivative';
            app.Temp_D = uieditfield(app.SendMessageTab, 'numeric');
            app.Temp_D.Editable = 'off';
            app.SetpointLabel = uilabel(app.SendMessageTab);
            app.SetpointLabel.Text = 'Setpoint';
            app.Temp_S = uieditfield(app.SendMessageTab, 'numeric');
            app.Temp_S.Limits = [-Inf -10];
            app.Temp_S.Editable = 'off';
            app.Temp_S.Value = -40;
            app.SetModeButtonGroup = uibuttongroup(app.SendMessageTab);
            app.SetModeButtonGroup.Title = 'Set Mode';
            app.ManualButton = uitogglebutton(app.SetModeButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.BasicButton = uitogglebutton(app.SetModeButtonGroup);
            app.BasicButton.Text = 'Basic';
            app.AdvancedButton = uitogglebutton(app.SetModeButtonGroup);
            app.AdvancedButton.Text = 'Advanced';
            app.TabGroup3 = uitabgroup(app.SendMessageTab);
            app.ManualTab = uitab(app.TabGroup3);
            app.ManualTab.Title = 'Manual';
            app.AdjustmentValuesLabel = uilabel(app.ManualTab);
            app.AdjustmentValuesLabel.Text = 'Adjustment Values';
            app.HeliumVentsLabel = uilabel(app.ManualTab);
            app.HeliumVentsLabel.Text = 'Helium Vent (s)';
            app.Helium_Vent = uieditfield(app.ManualTab, 'numeric');
            app.Helium_Vent.Editable = 'off';
            app.BallastDropsLabel = uilabel(app.ManualTab);
            app.BallastDropsLabel.Text = 'Ballast Drops';
            app.Ballast_Drop = uieditfield(app.ManualTab, 'numeric');
            app.Ballast_Drop.Editable = 'off';
            app.CutdownButton = uibutton(app.ManualTab, 'state');
            app.CutdownButton.Text = 'Cutdown';
            app.ManualAdjustmentButton = uibutton(app.ManualTab, 'state');
            app.ManualAdjustmentButton.Text = 'Manual Adjustment';
            app.BasicTab = uitab(app.TabGroup3);
            app.BasicTab.Title = 'Basic';
            app.SelectModeLabel = uilabel(app.BasicTab);
            app.SelectModeLabel.Text = 'Select Mode';
            app.VentTimesLabel = uilabel(app.BasicTab);
            app.VentTimesLabel.Text = 'Vent Time (s)';
            app.Vent_Time = uieditfield(app.BasicTab, 'numeric');
            app.Vent_Time.Editable = 'off';
            app.VentIncrementLabel = uilabel(app.BasicTab);
            app.VentIncrementLabel.Text = 'Vent Increment';
            app.Vent_Increment = uieditfield(app.BasicTab, 'numeric');
            app.Vent_Increment.Editable = 'off';
            app.BallastIncrementLabel = uilabel(app.BasicTab);
            app.BallastIncrementLabel.Text = 'Ballast Increment';
            app.Ballast_Increment = uieditfield(app.BasicTab, 'numeric');
            app.Ballast_Increment.Editable = 'off';
            app.AdvancedTab = uitab(app.TabGroup3);
            app.AdvancedTab.Title = 'Advanced';
            app.HeliumWeightsLabel = uilabel(app.AdvancedTab);
            app.HeliumWeightsLabel.Text = 'Helium Weights';
            app.AlphaLabel = uilabel(app.AdvancedTab);
            app.AlphaLabel.Text = 'Alpha';
            app.Helium_Alpha = uieditfield(app.AdvancedTab, 'numeric');
            app.Helium_Alpha.Limits = [0 10];
            app.Helium_Alpha.Editable = 'off';
            app.BetaLabel = uilabel(app.AdvancedTab);
            app.BetaLabel.Text = 'Beta';
            app.Helium_Beta = uieditfield(app.AdvancedTab, 'numeric');
            app.Helium_Beta.Editable = 'off';
            app.GammaLabel = uilabel(app.AdvancedTab);
            app.GammaLabel.Text = 'Gamma';
            app.Helium_Gamma = uieditfield(app.AdvancedTab, 'numeric');
            app.Helium_Gamma.Editable = 'off';
            app.BallastWeightsLabel = uilabel(app.AdvancedTab);
            app.BallastWeightsLabel.Text = 'Ballast Weights';
            app.BetaLabel_2 = uilabel(app.AdvancedTab);
            app.BetaLabel_2.Text = 'Beta';
            app.Ballast_Beta = uieditfield(app.AdvancedTab, 'numeric');
            app.Ballast_Beta.Editable = 'off';
            app.GammaLabel_2 = uilabel(app.AdvancedTab);
            app.GammaLabel_2.Text = 'Gamma';
            app.Ballast_Gamma = uieditfield(app.AdvancedTab, 'numeric');
            app.Ballast_Gamma.Editable = 'off';
            app.AlphaLabel_2 = uilabel(app.AdvancedTab);
            app.AlphaLabel_2.Text = 'Alpha';
            app.Ballast_Alpha = uieditfield(app.AdvancedTab, 'numeric');
            app.Ballast_Alpha.Editable = 'off';
            app.SendMessageButton = uibutton(app.SendMessageTab, 'state');
            app.SendMessageButton.ValueChangedFcn = createCallbackFcn(app, @SendMessageButtonValueChanged, true);
            app.SendMessageButton.Text = 'Send Message';
            app.LatitudeLabel_2 = uilabel(app.SendMessageTab);
            app.LatitudeLabel_2.Text = 'Latitude';
            app.Latitude = uieditfield(app.SendMessageTab, 'numeric');
            app.Latitude.Limits = [18 49];
            app.Latitude.Editable = 'off';
            app.Latitude.Value = 34;
            app.PositionLabel_2 = uilabel(app.SendMessageTab);
            app.PositionLabel_2.Text = 'Position';
            app.LongitudeLabel_2 = uilabel(app.SendMessageTab);
            app.LongitudeLabel_2.Text = 'Longitude';
            app.Longitude = uieditfield(app.SendMessageTab, 'numeric');
            app.Longitude.Limits = [-100 -69];
            app.Longitude.Editable = 'off';
            app.Longitude.Value = -84;
            app.SecondsLabel = uilabel(app.SendMessageTab);
            app.SecondsLabel.Text = 'Seconds';
            app.Update_Rate = uieditfield(app.SendMessageTab, 'numeric');
            app.Update_Rate.Limits = [0 2500];
            app.Update_Rate.Editable = 'off';
            app.Update_Rate.Value = 600;
            app.UpdateRateLabel = uilabel(app.SendMessageTab);
            app.UpdateRateLabel.Text = 'Update Rate';
            app.SentMessageListTab = uitab(app.TabGroup2);
            app.SentMessageListTab.Title = 'Sent Message List';
            app.UITable = uitable(app.SentMessageListTab);
            app.UITable.ColumnName = {'Command'; 'Value'; 'Time'};
            app.UITable.RowName = {};
            app.ExportDataButton = uibutton(app.SentMessageListTab, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.ViewRecievedMessagesTab = uitab(app.TabGroup2);
            app.ViewRecievedMessagesTab.Title = 'View Recieved Messages';
            app.LatitudeLabel = uilabel(app.ViewRecievedMessagesTab);
            app.LatitudeLabel.Text = 'Latitude';
            app.Latitude_rec = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Latitude_rec.Editable = 'off';
            app.LongitudeLabel = uilabel(app.ViewRecievedMessagesTab);
            app.LongitudeLabel.Text = 'Longitude';
            app.Longitude_rec = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Longitude_rec.Editable = 'off';
            app.AltitudeLabel = uilabel(app.ViewRecievedMessagesTab);
            app.AltitudeLabel.Text = 'Altitude';
            app.Altitude_rec = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Altitude_rec.Editable = 'off';
            app.PositionLabel = uilabel(app.ViewRecievedMessagesTab);
            app.PositionLabel.Text = 'Position';
            app.ToViewDataSelectTimeReceivedDropDownLabel = uilabel(app.ViewRecievedMessagesTab);
            app.ToViewDataSelectTimeReceivedDropDownLabel.Text = 'To View Data Select Time Received';
            app.TimeReceivedDropDown = uidropdown(app.ViewRecievedMessagesTab);
            app.TimeReceivedDropDown.Items = {'00-00-2018 00:00:00'};
            app.TimeReceivedDropDown.ValueChangedFcn = createCallbackFcn(app, @TimeReceivedDropDownValueChanged, true);
            app.TimeReceivedDropDown.Value = '00-00-2018 00:00:00';
            app.XComponentLabel_2 = uilabel(app.ViewRecievedMessagesTab);
            app.XComponentLabel_2.Text = 'X Component';
            app.Velocity_x = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Velocity_x.Editable = 'off';
            app.YComponentLabel_2 = uilabel(app.ViewRecievedMessagesTab);
            app.YComponentLabel_2.Text = 'Y Component';
            app.Velocity_y = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Velocity_y.Editable = 'off';
            app.ZComponentLabel_2 = uilabel(app.ViewRecievedMessagesTab);
            app.ZComponentLabel_2.Text = 'Z Component';
            app.Velocity_z = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Velocity_z.Editable = 'off';
            app.VelocityLabel = uilabel(app.ViewRecievedMessagesTab);
            app.VelocityLabel.Text = 'Velocity';
            app.AccelerationLabel = uilabel(app.ViewRecievedMessagesTab);
            app.AccelerationLabel.Text = 'Acceleration';
            app.XComponentLabel_3 = uilabel(app.ViewRecievedMessagesTab);
            app.XComponentLabel_3.Text = 'X Component';
            app.Acceleration_x = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Acceleration_x.Editable = 'off';
            app.YComponentLabel_3 = uilabel(app.ViewRecievedMessagesTab);
            app.YComponentLabel_3.Text = 'Y Component';
            app.Acceleration_y = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Acceleration_y.Editable = 'off';
            app.ZComponentLabel_3 = uilabel(app.ViewRecievedMessagesTab);
            app.ZComponentLabel_3.Text = 'Z Component';
            app.Acceleration_z = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Acceleration_z.Editable = 'off';
            app.TemperatureLabel = uilabel(app.ViewRecievedMessagesTab);
            app.TemperatureLabel.Text = 'Temperature';
            app.Temperature_rec = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Temperature_rec.Editable = 'off';
            app.PressureLabel = uilabel(app.ViewRecievedMessagesTab);
            app.PressureLabel.Text = 'Pressure';
            app.Pressure = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Pressure.Editable = 'off';
            app.AmbientConditionsLabel = uilabel(app.ViewRecievedMessagesTab);
            app.AmbientConditionsLabel.Text = 'Ambient Conditions';
            app.BatteryPercentageLabel = uilabel(app.ViewRecievedMessagesTab);
            app.BatteryPercentageLabel.Text = 'Battery Percentage';
            app.Battery = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Battery.Editable = 'off';
            app.PayloadStatusLabel = uilabel(app.ViewRecievedMessagesTab);
            app.PayloadStatusLabel.Text = 'Payload Status';
            app.HeliumVentedLabel = uilabel(app.ViewRecievedMessagesTab);
            app.HeliumVentedLabel.Text = 'Helium Vented';
            app.Helium_rec = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Helium_rec.Editable = 'off';
            app.PayloadStatusLabel_2 = uilabel(app.ViewRecievedMessagesTab);
            app.PayloadStatusLabel_2.Text = 'Payload Status';
            app.Ballast_rec = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Ballast_rec.Editable = 'off';
            app.BallastDroppedLabel = uilabel(app.ViewRecievedMessagesTab);
            app.BallastDroppedLabel.Text = 'Ballast Dropped';
            app.StateEditFieldLabel = uilabel(app.ViewRecievedMessagesTab);
            app.StateEditFieldLabel.Text = 'State';
            app.StateEditField = uieditfield(app.ViewRecievedMessagesTab, 'text');
            app.StateEditField.Value = 'Testing';
            app.EmergencyLampLabel = uilabel(app.ViewRecievedMessagesTab);
            app.EmergencyLampLabel.Text = 'Emergency';
            app.EmergencyLamp = uilamp(app.ViewRecievedMessagesTab);
            app.ControlEditFieldLabel = uilabel(app.ViewRecievedMessagesTab);
            app.ControlEditFieldLabel.Text = 'Control';
            app.ControlEditField = uieditfield(app.ViewRecievedMessagesTab, 'text');
            app.ControlEditField.Value = 'Advanced';
            app.RecievedDataGraphsTab = uitab(app.TabGroup2);
            app.RecievedDataGraphsTab.Title = 'Recieved Data Graphs';
            app.ExportDataButton_2 = uibutton(app.RecievedDataGraphsTab, 'push');
            app.ExportDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButton_2Pushed, true);
            app.ExportDataButton_2.Text = 'Export Data';
            app.DataTypeButtonGroup = uibuttongroup(app.RecievedDataGraphsTab);
            app.DataTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataTypeButtonGroupSelectionChanged, true);
            app.DataTypeButtonGroup.Title = 'Data Type';
            app.AltitudeButton = uiradiobutton(app.DataTypeButtonGroup);
            app.AltitudeButton.Text = 'Altitude';
            app.AltitudeButton.Value = true;
            app.LatitudeButton = uiradiobutton(app.DataTypeButtonGroup);
            app.LatitudeButton.Text = 'Latitude';
            app.TemperatureButton = uiradiobutton(app.DataTypeButtonGroup);
            app.TemperatureButton.Text = 'Temperature';
            app.PressureButton = uiradiobutton(app.DataTypeButtonGroup);
            app.PressureButton.Text = 'Pressure';
            app.BatteryButton = uiradiobutton(app.DataTypeButtonGroup);
            app.BatteryButton.Text = 'Battery';
            app.BallastDroppedButton = uiradiobutton(app.DataTypeButtonGroup);
            app.BallastDroppedButton.Text = 'Ballast Dropped';
            app.VertAccButton = uiradiobutton(app.DataTypeButtonGroup);
            app.VertAccButton.Text = 'Vert. Acc';
            app.VertVelocityButton = uiradiobutton(app.DataTypeButtonGroup);
            app.VertVelocityButton.Text = 'Vert. Velocity';
            app.HeliumVentedButton = uiradiobutton(app.DataTypeButtonGroup);
            app.HeliumVentedButton.Text = 'Helium Vented';
            app.LongitudeButton = uiradiobutton(app.DataTypeButtonGroup);
            app.LongitudeButton.Text = 'Longitude';
            app.UIAxes = uiaxes(app.RecievedDataGraphsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Y')
            app.AcknowledgeButton = uibutton(app.UIFigure, 'push');
            app.AcknowledgeButton.ButtonPushedFcn = createCallbackFcn(app, @AcknowledgeButtonPushed, true);
            app.AcknowledgeButton.Text = 'Acknowledge';
            app.NoNewMessagesLampLabel = uilabel(app.UIFigure);
            app.NoNewMessagesLampLabel.Text = 'No New Messages';
            app.NoNewMessagesLamp = uilamp(app.UIFigure);
            app.CheckMessagesButton = uibutton(app.UIFigure, 'push');
            app.CheckMessagesButton.ButtonPushedFcn = createCallbackFcn(app, @CheckMessagesButtonPushed, true);
            app.CheckMessagesButton.Text = 'Check Messages';
        end
    end

    methods (Access = public)

        % Construct app
        function app = guiHighBall

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end