classdef guiHighBall2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup2                  matlab.ui.container.TabGroup
        SendMessageTab             matlab.ui.container.Tab
        SetAltitudeLabel           matlab.ui.control.Label
        SetAltitude                matlab.ui.control.NumericEditField
        AltitudeBufferLabel        matlab.ui.control.Label
        AltitudeBuffer             matlab.ui.control.NumericEditField
        AltitudeLimitsLabel        matlab.ui.control.Label
        TemperaturePIDLabel        matlab.ui.control.Label
        PorportionalLabel          matlab.ui.control.Label
        Porportional               matlab.ui.control.NumericEditField
        IntegralLabel              matlab.ui.control.Label
        Integral                   matlab.ui.control.NumericEditField
        DerivativeLabel            matlab.ui.control.Label
        Derivative                 matlab.ui.control.NumericEditField
        SetpointLabel              matlab.ui.control.Label
        Setpoint                   matlab.ui.control.NumericEditField
        SetNewModeButtonGroup      matlab.ui.container.ButtonGroup
        ManualButton               matlab.ui.control.ToggleButton
        BasicButton                matlab.ui.control.ToggleButton
        AdvancedButton             matlab.ui.control.ToggleButton
        TabGroup3                  matlab.ui.container.TabGroup
        ManualTab                  matlab.ui.container.Tab
        AdjustmentValuesLabel      matlab.ui.control.Label
        VentHeliumsLabel           matlab.ui.control.Label
        VentHelium                 matlab.ui.control.NumericEditField
        DropBallastLabel           matlab.ui.control.Label
        DropBallast                matlab.ui.control.NumericEditField
        CutdownButton              matlab.ui.control.StateButton
        BasicTab                   matlab.ui.container.Tab
        SelectModeLabel            matlab.ui.control.Label
        HeliumVentTimeLabel        matlab.ui.control.Label
        HeliumVentTime             matlab.ui.control.NumericEditField
        HeliumIncrementLabel       matlab.ui.control.Label
        HeliumIncrement            matlab.ui.control.NumericEditField
        BallastIncrementLabel      matlab.ui.control.Label
        BallastIncrement           matlab.ui.control.NumericEditField
        MovementSetpointsLabel_2   matlab.ui.control.Label
        MinAccelerationLabel_2     matlab.ui.control.Label
        MinAcceleration_Basic      matlab.ui.control.NumericEditField
        MinVelocityLabel_2         matlab.ui.control.Label
        MinVelocity_Basic          matlab.ui.control.NumericEditField
        AdvancedTab                matlab.ui.container.Tab
        HeliumWeightsLabel         matlab.ui.control.Label
        AlphaLabel                 matlab.ui.control.Label
        HeliumAlpha                matlab.ui.control.NumericEditField
        BetaLabel                  matlab.ui.control.Label
        HeliumBeta                 matlab.ui.control.NumericEditField
        GammaLabel                 matlab.ui.control.Label
        HeliumGamma                matlab.ui.control.NumericEditField
        BallastWeightsLabel        matlab.ui.control.Label
        BetaLabel_2                matlab.ui.control.Label
        BallastBeta                matlab.ui.control.NumericEditField
        AlphaLabel_2               matlab.ui.control.Label
        BallastAlpha               matlab.ui.control.NumericEditField
        GammaLabel_2               matlab.ui.control.Label
        BallastGamma               matlab.ui.control.NumericEditField
        SendMessageButton          matlab.ui.control.StateButton
        CurrentModePanel           matlab.ui.container.Panel
        ManualModeLampLabel        matlab.ui.control.Label
        ManualModeLamp             matlab.ui.control.Lamp
        AdvancedModeLampLabel      matlab.ui.control.Label
        AdvancedModeLamp           matlab.ui.control.Lamp
        BasicModeLampLabel         matlab.ui.control.Label
        BasicModeLamp              matlab.ui.control.Lamp
        GoalPositionLabel          matlab.ui.control.Label
        LatitudeLabel_2            matlab.ui.control.Label
        SetLatitude                matlab.ui.control.NumericEditField
        LongitudeLabel_2           matlab.ui.control.Label
        SetLongitude               matlab.ui.control.NumericEditField
        UpdateRateLabel_2          matlab.ui.control.Label
        SecondsLabel               matlab.ui.control.Label
        UpdateRate                 matlab.ui.control.NumericEditField
        SentMessageListTab         matlab.ui.container.Tab
        UITable                    matlab.ui.control.Table
        ExportDataButton           matlab.ui.control.Button
        ViewRecievedMessagesTab    matlab.ui.container.Tab
        LatitudeLabel              matlab.ui.control.Label
        Latitude                   matlab.ui.control.NumericEditField
        LongitudeLabel             matlab.ui.control.Label
        Longitude                  matlab.ui.control.NumericEditField
        AltitudeLabel              matlab.ui.control.Label
        Altitude                   matlab.ui.control.NumericEditField
        PositionLabel              matlab.ui.control.Label
        ToViewDataSelectTimeReceivedDropDownLabel  matlab.ui.control.Label
        SelectTimeDropDown         matlab.ui.control.DropDown
        XComponentLabel            matlab.ui.control.Label
        VelocityX                  matlab.ui.control.NumericEditField
        YComponentLabel            matlab.ui.control.Label
        VelocityY                  matlab.ui.control.NumericEditField
        ZComponentLabel            matlab.ui.control.Label
        VelocityZ                  matlab.ui.control.NumericEditField
        VelocityLabel              matlab.ui.control.Label
        AccelerationLabel          matlab.ui.control.Label
        XComponentLabel_2          matlab.ui.control.Label
        AccX                       matlab.ui.control.NumericEditField
        YComponentLabel_2          matlab.ui.control.Label
        AccY                       matlab.ui.control.NumericEditField
        ZComponentLabel_2          matlab.ui.control.Label
        AccZ                       matlab.ui.control.NumericEditField
        TemperatureLabel           matlab.ui.control.Label
        Temperature                matlab.ui.control.NumericEditField
        PressureLabel              matlab.ui.control.Label
        Pressure                   matlab.ui.control.NumericEditField
        AmbientConditionsLabel     matlab.ui.control.Label
        PayloadStateLabel          matlab.ui.control.Label
        HeliumVentedLabel          matlab.ui.control.Label
        HeliumVented               matlab.ui.control.NumericEditField
        PayloadStatusLabel_2       matlab.ui.control.Label
        BallastDropped             matlab.ui.control.NumericEditField
        BallastDroppedLabel        matlab.ui.control.Label
        EmergencyLampLabel         matlab.ui.control.Label
        EmergencyLamp              matlab.ui.control.Lamp
        StateEditFieldLabel        matlab.ui.control.Label
        StateEditField             matlab.ui.control.EditField
        BatteryPercentageLabel     matlab.ui.control.Label
        BatteryPercent             matlab.ui.control.NumericEditField
        ControlModeEditFieldLabel  matlab.ui.control.Label
        ControlModeEditField       matlab.ui.control.EditField
        RecievedDataGraphsTab      matlab.ui.container.Tab
        ExportDataButton_2         matlab.ui.control.Button
        DataTypeButtonGroup        matlab.ui.container.ButtonGroup
        AltitudeButton             matlab.ui.control.RadioButton
        LatitudeButton             matlab.ui.control.RadioButton
        TemperatureButton          matlab.ui.control.RadioButton
        PressureButton             matlab.ui.control.RadioButton
        BatteryButton              matlab.ui.control.RadioButton
        BallastDroppedButton       matlab.ui.control.RadioButton
        XAccelerationButton        matlab.ui.control.RadioButton
        YAccelerationButton        matlab.ui.control.RadioButton
        ZAccelerationButton        matlab.ui.control.RadioButton
        XVelocityButton            matlab.ui.control.RadioButton
        YVelocityButton            matlab.ui.control.RadioButton
        ZVelocityButton            matlab.ui.control.RadioButton
        HeliumVentedButton         matlab.ui.control.RadioButton
        LongitudeButton            matlab.ui.control.RadioButton
        UIAxes                     matlab.ui.control.UIAxes
        AcknowledgeButton          matlab.ui.control.Button
        NoNewMessagesLampLabel     matlab.ui.control.Label
        NewMessagesLamp            matlab.ui.control.Lamp
    end

   
     properties (Access = private)
        RecievedQueue % Queue of reccieved messages with time stamps
        RecievedTimes % cell of time strings
        OutgoingMessage % message to payload
        OutgoingData % data to encode in message
        t
        times = 0;
    end
    
    methods (Access = private)
        
        function DecodeMessage(app, file_name)

            FID = fopen(file_name);
            Encoded = fread(FID, 'ubit1');
            fclose('all');
            
            temp = datetime('now','TimeZone','local');
            date = datestr(temp, 'dd-mm-yy_HH-MM-ss');
            
            app.RecievedQueue = [cell(1,19); app.RecievedQueue];
            app.RecievedQueue{1,19} = date;
            
            % Temperature Data
            TEMP_MIN = -88;
            TEMP_STEP = .25;
            TEMP_OFFSET = 128;
            TEMP_BITS = 9;
            value = Encoded(TEMP_OFFSET+1:TEMP_OFFSET+TEMP_BITS)
            app.RecievedQueue{1,1} = decode(app, TEMP_MIN, TEMP_STEP, value);
            % Pressure Data
            PRS_MIN = 0;
            PRS_STEP = .25;
            PRS_OFFSET = 167;
            PRS_BITS = 12;
            app.RecievedQueue{1,2} = decode(app, PRS_MIN, PRS_STEP, Encoded(PRS_OFFSET+1:PRS_OFFET+PRS_BITS));
            % IMU Data
            ACC_MAG_MIN = 0;
            ACC_MAG_STEP = .001;
            ACC_MAG_OFFSET = 95;
            ACC_MAG_BITS = 14;
            AccMag = decode(app, ACC_MAG_MIN, ACC_MAG_STEP, Encoded(ACC_MAG_OFFSET+1:ACC_MAG_OFFET+ACC_MAG_BITS));
            
            ACC_THETA_MIN = 0;
            ACC_THETA_STEP = 0.0125663706;
            ACC_THETA_OFFSET = 109;
            ACC_THETA_BITS = 10;
            AccTheta = decode(app, ACC_THETA_MIN, ACC_THETA_STEP, Encoded(ACC_THETA_OFFSET+1:ACC_THETA_OFFET+ACC_THETA_BITS));
            
            ACC_PHI_MIN = 0;
            ACC_PHI_STEP = 0.00628318531;
            ACC_PHI_OFFSET = 119;
            ACC_PHI_BITS = 9;
            AccPhi = decode(app, ACC_PHI_MIN, ACC_PHI_STEP, Encoded(ACC_PHI_OFFSET+1:ACC_PHI_OFFET+ACC_PHI_BITS));
            
            app.RecievedQueue{1,3} = AccMag*cos(AccPhi);
            app.RecievedQueue{1,4} = AccMag*sin(AccPhi);
            app.RecievedQueue{1,5} = AccTheta;
            
            % GPS Data
            ALT_MIN = -5000;
            ALT_STEP = 3;
            ALT_OFFSET = 0;
            ALT_BITS = 14;
            app.RecievedQueue{1,6} = decode(app, ALT_MIN, ALT_STEP, Encoded(ALT_OFFSET+1:ALT_OFFET+ALT_BITS));
            
            LAT_MIN = -100;
            LAT_STEP = .0001;
            LAT_OFFSET = 14;
            LAT_BITS = 23;
            app.RecievedQueue{1,7}= decode(app, LAT_MIN, LAT_STEP, Encoded(LAT_OFFSET+1:LAT_OFFET+LAT_BITS));
            
            LONG_MIN = 18;
            LONG_STEP = .0001;
            LONG_OFFSET = 37;
            LONG_BITS = 23;
            app.RecievedQueue{1,8} = decode(app, LONG_MIN, LONG_STEP, Encoded(LONG_OFFSET+1:LONG_OFFET+LONG_BITS));
            
            % Velovity Data
            VEL_MAG_MIN = 0;
            VEL_MAG_STEP = 0.001;
            VEL_MAG_OFFSET = 60;
            VEL_MAG_BITS = 16;
            VelMag = decode(app, VEL_MAG_MIN, VEL_MAG_STEP, Encoded(VEL_MAG_OFFSET+1:VEL_MAG_OFFET+VEL_MAG_BITS));
            
            VEL_THETA_MIN = 0;
            VEL_THETA_STEP = 0.0125663706;
            VEL_THETA_OFFSET = 76;
            VEL_THETA_BITS = 10;
            VelTheta = decode(app, VEL_THETA_MIN, VEL_THETA_STEP, Encoded(VEL_THETA_OFFSET+1:VEL_THETA_OFFET+VEL_THETA_BITS));
            
            VEL_PHI_MIN = 0;
            VEL_PHI_STEP = 0.00628318531;
            VEL_PHI_OFFSET = 86;
            VEL_PHI_BITS = 9;
            VelPhi = decode(app, VEL_PHI_MIN, VEL_PHI_STEP, Encoded(VEL_PHI_OFFSET+1:VEL_PHI_OFFET+VEL_PHI_BITS));
            
            app.RecievedQueue{1,9} = VelMag*cos(VelPhi);
            app.RecievedQueue{1,10} = VelMag*sin(VelPhi);
            app.RecievedQueue{1,11} = VelTheta;
            
            % Helium Data
            HEL_MIN = 0;
            HEL_STEP = 1;
            HEL_OFFSET = 137;
            HEL_BITS = 10;
            app.RecievedQueue{1,12} = decode(app, HEL_MIN, HEL_STEP, Encoded(HEL_OFFSET+1:HEL_OFFET+HEL_BITS));
            
            % Ballast Data
            BALL_MIN = 0;
            BALL_STEP = .1;
            BALL_OFFSET = 137;
            BALL_BITS = 10;
            app.RecievedQueue{1,13} = decode(app, BALL_MIN, BALL_STEP, Encoded(BALL_OFFSET+1:BALL_OFFET+BALL_BITS));
            
            % Battery Data
            BATT_MIN = 0;
            BATT_STEP = .1;
            BATT_OFFSET = 147;
            BATT_BITS = 10;
            app.RecievedQueue{1,14} = decode(app, BATT_MIN, BATT_STEP, Encoded(BATT_OFFSET+1:BATT_OFFET+BATT_BITS));
            
            % Time Data
            TIME_MIN = 0;
            TIME_STEP = 1;
            TIME_OFFSET = 169;
            TIME_BITS = 17;
            app.RecievedQueue{1,15} = decode(app, TIME_MIN, TIME_STEP, Encoded(TIME_OFFSET+1:TIME_OFFET+TIME_BITS));
            
            % State Data
            STATE_OFFSET = 196;
            STATE_BITS = 3;
            app.RecievedQueue{1,16} = decode(app, 0, 1, Encoded(STATE_OFFSET+1:STATE_OFFET+STATE_BITS));
            
            % Control Mode Data
            CRTL_OFFSET = 199;
            CRTL_BITS = 2;
            app.RecievedQueue{1,17} = decode(app, 0, 1, Encoded(CRTL_OFFSET+1:CRTL_OFFET+CRTL_BITS));
            
            % Emergency Data
            EMRG_OFFSET = 201;
            EMRG_BITS = 2;
            app.RecievedQueue{1,18} = decode(app, 0, 1, Encoded(EMRG_OFFSET+1:EMRG_OFFET+EMRG_BITS));
        
        end
        
        function value = decode(app, min, step, bin)
            input = bin2dec(bin);
            value = (input*step)+min;
        end
        
        function message = encode_message(app)
            message = false(1,240);      
            
            temp = datetime('now','TimeZone','local');
            date = datestr(temp, 'dd-mm-yy_HH-MM-ss');
            
            app.OutgoingData = [cell(1,24); app.OutgoingData];
            app.OutgoingData{1,24} = date;
            
            %altitude
            ALT_MIN = 0;
            ALT_STEP = 5;
            ALT_OFFSET = 0;
            ALT_LENGTH = 13;
            ALT_VALUE = (app.SetAltitude.Value()- ALT_MIN)/ALT_STEP;
            message(1+ALT_OFFSET:ALT_OFFSET+ALT_LENGTH) = int2bin(app, ALT_VALUE, ALT_LENGTH);
            app.OutgoingData{1,1} = app.SetAltitude.Value();
            
            % altitude_buffer
            ALT_BUF_MIN = 0;
            ALT_BUF_STEP = 5;
            ALT_BUF_OFFSET = 13;
            ALT_BUF_LENGTH = 10;
            ALT_BUF_VALUE = (app.AltitudeBuffer.Value()- ALT_BUF_MIN)/ALT_BUF_STEP;
            message(1+ALT_BUF_OFFSET:ALT_BUF_OFFSET+ALT_BUF_LENGTH) = int2bin(app, ALT_BUF_VALUE, ALT_BUF_LENGTH);
            app.OutgoingData{1,2} = app.AltitudeBuffer.Value();
            
            % Latitude
            lat_degree = floor(app.SetLatitude.Value());
            lat_min = floor(60*(app.SetLatitude.Value()-lat_degree));
            
            app.OutgoingData{1,3} = app.SetLatitude.Value();
            
            % lat_degree
            LAT_MIN = 18;
            LAT_STEP = 1;
            LAT_OFFSET = 23;
            LAT_LENGTH = 5;
            LAT_VALUE = (lat_degree- LAT_MIN)/LAT_STEP;
            message(1+LAT_OFFSET:LAT_OFFSET+LAT_LENGTH) = int2bin(app, LAT_VALUE, LAT_LENGTH);
            
            % lat_deg_min
            LAT_MIN_MIN = -10;
            LAT_MIN_STEP = .0001;
            LAT_MIN_OFFSET = 28;
            LAT_MIN_LENGTH = 18;
            LAT_MIN_VALUE = (lat_min- LAT_MIN_MIN)/LAT_MIN_STEP;
            message(1+LAT_MIN_OFFSET:LAT_MIN_OFFSET+LAT_MIN_LENGTH) = int2bin(app, LAT_MIN_VALUE, LAT_MIN_LENGTH);
            
            % Longitude
            long_degree = floor(app.SetLongitude.Value());
            long_min = floor(60*(app.SetLongitude.Value()-long_degree));
            
            app.OutgoingData{1,4} = app.SetLongitude.Value();
            
            % long_deg
            LONG_MIN = -100;
            LONG_STEP = 1;
            LONG_OFFSET = 46;
            LONG_LENGTH = 5;
            LONG_VALUE = (long_degree- LONG_MIN)/LONG_STEP;
            message(1+LONG_OFFSET:LONG_OFFSET+LONG_LENGTH) = int2bin(app, LONG_VALUE, LONG_LENGTH);
            
            % long_deg_MIN = 
            LONG_MIN_MIN = -10;
            LONG_MIN_STEP = .0001;
            LONG_MIN_OFFSET = 51;
            LONG_MIN_LENGTH = 18;
            LONG_MIN_VALUE = (long_min- LONG_MIN_MIN)/LONG_MIN_STEP;
            message(1+LONG_MIN_OFFSET:LONG_MIN_OFFSET+LONG_MIN_LENGTH) = int2bin(app, LONG_MIN_VALUE, LONG_MIN_LENGTH);
            
            % cutdown
            CUT_MIN = 0;
            CUT_STEP = 1;
            CUT_OFFSET = 69;
            CUT_LENGTH = 2;
            CUT_VALUE = (app.CutdownButton.Value()- CUT_MIN)/CUT_STEP;
            message(1+CUT_OFFSET:CUT_OFFSET+CUT_LENGTH) = int2bin(app, CUT_VALUE, CUT_LENGTH);
            app.OutgoingData{1,5} = app.CutdownButton.Value();
            
            % update_rate
            UPD_MIN = 0;
            UPD_STEP = 30;
            UPD_OFFSET = 71;
            UPD_LENGTH = 9;
            UPD_VALUE = (app.UpdateRate.Value()- UPD_MIN)/UPD_STEP;
            message(1+UPD_OFFSET:UPD_OFFSET+UPD_LENGTH) = int2bin(app, UPD_VALUE, UPD_LENGTH);
            app.OutgoingData{1,6} = app.UpdateRate.Value();
            
            % hel_alpha
            HEL_A_MIN = 0;
            HEL_A_STEP = .01;
            HEL_A_OFFSET = 80;
            HEL_A_LENGTH = 10;
            HEL_A_VALUE = (app.HeliumAlpha.Value()- HEL_A_MIN)/HEL_A_STEP;
            message(1+HEL_A_OFFSET:HEL_A_OFFSET+HEL_A_LENGTH) = int2bin(app, HEL_A_VALUE, HEL_A_LENGTH);
            app.OutgoingData{1,7} = app.HeliumAlpha.Value();
            
            % hel_beta
            HEL_B_MIN = 0;
            HEL_B_STEP = .01;
            HEL_B_OFFSET = 90;
            HEL_B_LENGTH = 10;
            HEL_B_VALUE = (app.HeliumBeta.Value()- HEL_B_MIN)/HEL_B_STEP;
            message(1+HEL_B_OFFSET:HEL_B_OFFSET+HEL_B_LENGTH) = int2bin(app, HEL_B_VALUE, HEL_B_LENGTH);
            app.OutgoingData{1,8} = app.HeliumBeta.Value();
            
            % hel_gamma
            HEL_G_MIN = 0;
            HEL_G_STEP = .01;
            HEL_G_OFFSET = 100;
            HEL_G_LENGTH = 10;
            HEL_G_VALUE = (app.HeliumGamma.Value()- HEL_G_MIN)/HEL_G_STEP;
            message(1+HEL_G_OFFSET:HEL_G_OFFSET+HEL_G_LENGTH) = int2bin(app, HEL_G_VALUE, HEL_G_LENGTH);
            app.OutgoingData{1,9} = app.HeliumGamma.Value();
            
            % bal_alpha
            BAL_A_MIN = 0;
            BAL_A_STEP = .01;
            BAL_A_OFFSET = 110;
            BAL_A_LENGTH = 10;
            BAL_A_VALUE = (app.BallastAlpha.Value()- BAL_A_MIN)/BAL_A_STEP;
            message(1+BAL_A_OFFSET:BAL_A_OFFSET+BAL_A_LENGTH) = int2bin(app, BAL_A_VALUE, BAL_A_LENGTH);
            app.OutgoingData{1,10} = app.BallastAlpha.Value();
            
            % bal_beta 
            BAL_B_MIN = 0;
            BAL_B_STEP = .01;
            BAL_B_OFFSET = 120;
            BAL_B_LENGTH = 10;
            BAL_B_VALUE = (app.BallastBeta.Value()- BAL_B_MIN)/BAL_B_STEP;
            message(1+BAL_B_OFFSET:BAL_B_OFFSET+BAL_B_LENGTH) = int2bin(app, BAL_B_VALUE, BAL_B_LENGTH);
            app.OutgoingData{1,11} = app.BallastBeta.Value();
            
            % bal_gamma
            BAL_G_MIN = 0;
            BAL_G_STEP = .01;
            BAL_G_OFFSET = 130;
            BAL_G_LENGTH = 10;
            BAL_G_VALUE = (app.BallastGamma.Value()- BAL_G_MIN)/BAL_G_STEP;
            message(1+BAL_G_OFFSET:BAL_G_OFFSET+BAL_G_LENGTH) = int2bin(app, BAL_G_VALUE, BAL_G_LENGTH);
            app.OutgoingData{1,12} = app.BallastGamma.Value();
            
            % length_vent
            VENT_TIME_MIN = 0;
            VENT_TIME_STEP = .1;
            VENT_TIME_OFFSET = 140;
            VENT_TIME_LENGTH = 10;
            VENT_TIME_VALUE = (app.HeliumVentTime.Value()- VENT_TIME_MIN)/VENT_TIME_STEP;
            message(1+VENT_TIME_OFFSET:VENT_TIME_OFFSET+VENT_TIME_LENGTH) = int2bin(app, VENT_TIME_VALUE, VENT_TIME_LENGTH);
            app.OutgoingData{1,13} = app.HeliumVentTime.Value();
            
            % vent_inc
            VENT_INC_MIN = 0;
            VENT_INC_STEP = 1;
            VENT_INC_OFFSET = 150;
            VENT_INC_LENGTH = 7;
            VENT_INC_VALUE = (app.HeliumIncrement.Value()- VENT_INC_MIN)/VENT_INC_STEP;
            message(1+VENT_INC_OFFSET:VENT_INC_OFFSET+VENT_INC_LENGTH) = int2bin(app, VENT_INC_VALUE, VENT_INC_LENGTH);
            app.OutgoingData{1,14} = app.HeliumIncrement.Value();
            
            % ballast_inc
            BAL_INC_MIN = 0;
            BAL_INC_STEP = 1;
            BAL_INC_OFFSET = 157;
            BAL_INC_LENGTH = 7;
            BAL_INC_VALUE = (app.BallastIncrement.Value()- BAL_INC_MIN)/BAL_INC_STEP;
            message(1+BAL_INC_OFFSET:BAL_INC_OFFSET+BAL_INC_LENGTH) = int2bin(app, BAL_INC_VALUE, BAL_INC_LENGTH);
            app.OutgoingData{1,15} = app.BallastIncrement.Value();
            
            % temp_setpoint
            TEMP_S_MIN = -70;
            TEMP_S_STEP = .5;
            TEMP_S_OFFSET = 164;
            TEMP_S_LENGTH = 7;
            TEMP_S_VALUE = (app.Setpoint.Value()- TEMP_S_MIN)/TEMP_S_STEP;
            message(1+TEMP_S_OFFSET:TEMP_S_OFFSET+TEMP_S_LENGTH) = int2bin(app, TEMP_S_VALUE, TEMP_S_LENGTH);
            app.OutgoingData{1,16} = app.Setpoint.Value();
            
            % temp_P
            TEMP_P_MIN = 0;
            TEMP_P_STEP = .001;
            TEMP_P_OFFSET = 171;
            TEMP_P_LENGTH = 14;
            TEMP_P_VALUE = (app.Porportional.Value()- TEMP_P_MIN)/TEMP_P_STEP;
            message(1+TEMP_P_OFFSET:TEMP_P_OFFSET+TEMP_P_LENGTH) = int2bin(app, TEMP_P_VALUE, TEMP_P_LENGTH);
            app.OutgoingData{1,17} = app.Porportional.Value();
            
            % temp_I
            TEMP_I_MIN = 0;
            TEMP_I_STEP = .001;
            TEMP_I_OFFSET = 185;
            TEMP_I_LENGTH = 14;
            TEMP_I_VALUE = (app.Integral.Value()- TEMP_I_MIN)/TEMP_I_STEP;
            message(1+TEMP_I_OFFSET:TEMP_I_OFFSET+TEMP_I_LENGTH) = int2bin(app, TEMP_I_VALUE, TEMP_I_LENGTH);
            app.OutgoingData{1,18} = app.Integral.Value();
            
            % temp_D
            TEMP_D_MIN = 0;
            TEMP_D_STEP = .001;
            TEMP_D_OFFSET = 199;
            TEMP_D_LENGTH = 14;
            TEMP_D_VALUE = (app.Derivative.Value()- TEMP_D_MIN)/TEMP_D_STEP;
            message(1+TEMP_D_OFFSET:TEMP_D_OFFSET+TEMP_D_LENGTH) = int2bin(app, TEMP_D_VALUE, TEMP_D_LENGTH);
            app.OutgoingData{1,19} = app.Derivative.Value(); 
            
            % control_mode
            if app.ManualButton.Value()
                control = 1;
            elseif app.BasiButton.Value()
                control = 2;
            elseif app.AdvancedButton.Value()
                control = 3;
            end
            CTRL_MIN = 0;
            CTRL_STEP = 1;
            CTRL_OFFSET = 213;
            CTRL_LENGTH = 2;
            CTRL_VALUE = (control- CTRL_MIN)/CTRL_STEP;
            message(1+CTRL_OFFSET:CTRL_OFFSET+CTRL_LENGTH) = int2bin(app, CTRL_VALUE, CTRL_LENGTH);
            app.OutgoingData{1,20} = control; 
            
            % determine action
            helium = app.VentHelium.Value();
            ballast = app.DropBallast.Value();
            if round(helium) ~= 0
                amount = helium;
                adjust = true;
                select = false;
            elseif round(ballast) ~= 0
                amount = ballast;
                adjust = true;
                select = true;
            else
                amount = 0;
                adjust = false;
                select = false;
            end
            
            app.OutgoingData{1,21} = adjust;
            app.OutgoingData{1,22} = select;
            app.OutgoingData{1,23} = amount;
            
            % manual_adjust
            MAN_ADJ_OFFSET = 215;
            message(1+MAN_ADJ_OFFSET) = adjust;
            
            % manual_select
            MAN_SEL_OFFSET = 216;
            message(1+MAN_SEL_OFFSET) = select;
            
            % manual_amount
            MAN_AMT_MIN = 0;
            MAN_AMT_STEP = 1;
            MAN_AMT_OFFSET = 217;
            MAN_AMT_LENGTH = 10;
            MAN_AMT_VALUE = (amount- MAN_AMT_MIN)/MAN_AMT_STEP;
            message(1+MAN_AMT_OFFSET:MAN_AMT_OFFSET+MAN_AMT_LENGTH) = int2bin(app, MAN_AMT_VALUE, MAN_AMT_LENGTH);

        end
        
        function bin = int2bin(app, integer, long)
            integer = round(integer);
            if integer == 0
                bin = zeros(1,long);
            elseif integer < 0
                bin = zeros(1,long);
                f = uifigure;
                message = sprintf('Value below expected minimum! \n Consider changing input.');
                uialert(f,message,'Warning',...
                    'Icon','warning');
            else
                l = floor(log2(integer));
                
                integer = integer - 2^l;
                bin = true(1,l+1);
                for val = l-1:-1:0
                    if (integer < 2^val)
                        bin(l+1-val) = false;
                    else
                        integer = integer - 2^val;
                    end
                end
                if length(bin) ~= long
                    start = false(1,(long-l-1));
                    bin = [start, bin];
                end
            end
        end
        
        function checkMessages(app, num)
            num;
            % Read Unread Emails in the folder
            mails = ReadOutlook(...
                'Folder', 'Senior Design SBD',...
                'Savepath', 'C:\Users\Kyle\Documents\GitHub\HighBall_Senior_Design\Software\Ground Communication\Incoming',...
                'Read',1 , ...
                'Mark',1);
            
            % Read data from incoming file and move it to another folder
            cd Incoming
            list = dir( '*data*.sbd');
            cd ..
            num = length(list);
            
            if num > 0
                if num == 1
                    app.NoNewMessagesLampLabel.Text = '1 New Message';
                else
                    app.NoNewMessagesLampLabel.Text = [num2str(num), ' New Messages'];
                end
                app.NewMessagesLamp.Color = 'green';
                temp = datetime('now','TimeZone','local');
                date = datestr(temp, 'dd-mm-yy_HH-MM-ss');
            else
                temp = datetime('now','TimeZone','local');
                date = datestr(temp, 'dd-mm-yy_HH-MM-ss');
                disp(['Run CheckMessage @ ', date])
            end
            
            while(num > 0)
                name = ['Incoming/',list(num).name;];
                DecodeMessage(app, name);
                app.SelectTimeDropDown.Items = [{date}; app.SelectTimeDropDown.Items];
                app.times = app.times + 1;
                app.SelectTimeDropDown.ItemsData = 1:app.times;
                movefile(['Incoming/',list(num).name;], 'Read');
                num = num - 1;
            end
        end
        
        function SendMessage(app)
            bin = app.OutgoingMessage;
            
            % Write data to binary .sbd filed
            temp = datetime('now','TimeZone','local');
            date = datestr(temp, 'dd-mm-yy_HH-MM-ss');
            file_name = [date,'data.sbd'];
            FID = fopen(file_name, 'w+');
            fwrite(FID, bin, 'ubit1');
            fclose('all');
            
            % Send email with sbd Attachment
            
            % Define these variables appropriately:
            mail = 'teamHighBall@yahoo.com'; %Your Yahoo email address
            password = 'MITLLHighBall2018';  %Your Yahoo password
            setpref('Internet','SMTP_Server','smtp.mail.yahoo.com');
            setpref('Internet','E_mail',mail);
            setpref('Internet','SMTP_Username',mail);
            setpref('Internet','SMTP_Password',password);
            props = java.lang.System.getProperties;
            props.setProperty('mail.smtp.auth','true');
            props.setProperty('mail.smtp.socketFactory.class', 'javax.net.ssl.SSLSocketFactory');
            props.setProperty('mail.smtp.socketFactory.port','465');
            
            % to_mail =  'data@sbd.iridium.com'; % use this email for messages
            to_mail = 'kwatters6@gatech.edu'; % change to my email for testing
            % ISU = '300434062690230'; % ISU number here
            ISU = 'TestNumber';
            
            sendmail(to_mail, ISU, 'Message is Unrelated', file_name)
            
            % Move sent data to another folder and record data in queue
            movefile([date,'data.sbd'], 'Sent');
            app.UITable.Data = [cell(1,24); app.UITable.Data];
            app.UITable.Data(1,1:24) = app.OutgoingData;
            app.OutgoingData = cell(0);
            app.OutgoingMessage = [];
        end
        
        function confirm_rem(app,src,event)
            display(event.SelectedOption);
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RecievedTimes = cell(0);  % Queue of reccieved messages with time stamps
            app.RecievedQueue = [];
            app.OutgoingMessage = []; % message to payload
            app.OutgoingData = cell(0); % data to encode in message
            
            app.t = timer('ExecutionMode','fixedSpacing', ...
                'Name', 'CheckMailTimer', ...
                'TasksToExecute', 100, ...
                'Period', 180);
            app.t.TimerFcn =  @(~, ~) app.checkMessages(7);
            start(app.t);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: SendMessageButton
        function SendMessageButtonValueChanged(app, event)
            Value = app.SendMessageButton.Value();
            if Value == true
                app.OutgoingMessage = encode_message(app);
                printed = 'Finished Encoding'
                SendMessage(app);
                app.SendMessageButton.Value = false;
            end
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            
        end

        % Value changed function: SelectTimeDropDown
        function SelectTimeDropDownValueChanged(app, event)
            n = app.SelectTimeDropDown.Value;
            app.Altitude.Value = app.RecievedQueue(n,6);
            app.Longitude.Value = app.RecievedQueue(n,7);
            app.Latitude.Value = app.RecievedQueue(n,8);
            app.VelocityX.Value = app.RecievedQueue(n,9);
            app.VelocityY.Value = app.RecievedQueue(n,10);
            app.VelocityZ.Value = app.RecievedQueue(n,11);
            app.AccX.Value = app.RecievedQueue(n,3);
            app.AccY.Value = app.RecievedQueue(n,4);
            app.AccZ.Value = app.RecievedQueue(n,5);
            app.Temperature.Value = app.RecievedQueue(n,1);
            app.Pressure.Value = app.RecievedQueue(n,2);
            app.HeliumVented .Value = app.RecievedQueue(n,12);
            app.BallastDropped.Value = app.RecievedQueue(n,13);
            app.BatteryPercent.Value = app.RecievedQueue(n,14);
            app.EmergencyLamp.Value = app.RecievedQueue(n,18);
            app.StateEditField.Value = app.RecievedQueue(n,16);
            app.ControlModeEditField.Value = app.RecievedQueue(n,17);
        end

        % Button pushed function: ExportDataButton_2
        function ExportDataButton_2Pushed(app, event)
            
        end

        % Selection changed function: DataTypeButtonGroup
        function DataTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataTypeButtonGroup.SelectedObject;
            
        end

        % Button pushed function: AcknowledgeButton
        function AcknowledgeButtonPushed(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.SendMessageTab = uitab(app.TabGroup2);
            app.SendMessageTab.Title = 'Send Message';
            app.SetAltitudeLabel = uilabel(app.SendMessageTab);
            app.SetAltitudeLabel.Text = 'Set Altitude';
            app.SetAltitude = uieditfield(app.SendMessageTab, 'numeric');
            app.AltitudeBufferLabel = uilabel(app.SendMessageTab);
            app.AltitudeBufferLabel.Text = 'Altitude Buffer';
            app.AltitudeBuffer = uieditfield(app.SendMessageTab, 'numeric');
            app.AltitudeLimitsLabel = uilabel(app.SendMessageTab);
            app.AltitudeLimitsLabel.Text = 'Altitude Limits';
            app.TemperaturePIDLabel = uilabel(app.SendMessageTab);
            app.TemperaturePIDLabel.Text = 'Temperature PID';
            app.PorportionalLabel = uilabel(app.SendMessageTab);
            app.PorportionalLabel.Text = 'Porportional';
            app.Porportional = uieditfield(app.SendMessageTab, 'numeric');
            app.IntegralLabel = uilabel(app.SendMessageTab);
            app.IntegralLabel.Text = 'Integral';
            app.Integral = uieditfield(app.SendMessageTab, 'numeric');
            app.DerivativeLabel = uilabel(app.SendMessageTab);
            app.DerivativeLabel.Text = 'Derivative';
            app.Derivative = uieditfield(app.SendMessageTab, 'numeric');
            app.SetpointLabel = uilabel(app.SendMessageTab);
            app.SetpointLabel.Text = 'Setpoint';
            app.Setpoint = uieditfield(app.SendMessageTab, 'numeric');
            app.Setpoint.Value = -70;
            app.SetNewModeButtonGroup = uibuttongroup(app.SendMessageTab);
            app.SetNewModeButtonGroup.Title = 'Set New Mode';
            app.ManualButton = uitogglebutton(app.SetNewModeButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.BasicButton = uitogglebutton(app.SetNewModeButtonGroup);
            app.BasicButton.Text = 'Basic';
            app.AdvancedButton = uitogglebutton(app.SetNewModeButtonGroup);
            app.AdvancedButton.Text = 'Advanced';
            app.TabGroup3 = uitabgroup(app.SendMessageTab);
            app.ManualTab = uitab(app.TabGroup3);
            app.ManualTab.Title = 'Manual';
            app.AdjustmentValuesLabel = uilabel(app.ManualTab);
            app.AdjustmentValuesLabel.Text = 'Adjustment Values';
            app.VentHeliumsLabel = uilabel(app.ManualTab);
            app.VentHeliumsLabel.Text = 'Vent Helium (s)';
            app.VentHelium = uieditfield(app.ManualTab, 'numeric');
            app.DropBallastLabel = uilabel(app.ManualTab);
            app.DropBallastLabel.Text = 'Drop Ballast';
            app.DropBallast = uieditfield(app.ManualTab, 'numeric');
            app.CutdownButton = uibutton(app.ManualTab, 'state');
            app.CutdownButton.Text = 'Cutdown';
            app.BasicTab = uitab(app.TabGroup3);
            app.BasicTab.Title = 'Basic';
            app.SelectModeLabel = uilabel(app.BasicTab);
            app.SelectModeLabel.Text = 'Select Mode';
            app.HeliumVentTimeLabel = uilabel(app.BasicTab);
            app.HeliumVentTimeLabel.Text = 'Helium Vent Time';
            app.HeliumVentTime = uieditfield(app.BasicTab, 'numeric');
            app.HeliumIncrementLabel = uilabel(app.BasicTab);
            app.HeliumIncrementLabel.Text = 'Helium Increment';
            app.HeliumIncrement = uieditfield(app.BasicTab, 'numeric');
            app.BallastIncrementLabel = uilabel(app.BasicTab);
            app.BallastIncrementLabel.Text = 'Ballast Increment';
            app.BallastIncrement = uieditfield(app.BasicTab, 'numeric');
            app.MovementSetpointsLabel_2 = uilabel(app.BasicTab);
            app.MovementSetpointsLabel_2.Text = 'Movement Setpoints';
            app.MinAccelerationLabel_2 = uilabel(app.BasicTab);
            app.MinAccelerationLabel_2.Text = 'Min Acceleration';
            app.MinAcceleration_Basic = uieditfield(app.BasicTab, 'numeric');
            app.MinVelocityLabel_2 = uilabel(app.BasicTab);
            app.MinVelocityLabel_2.Text = 'Min Velocity';
            app.MinVelocity_Basic = uieditfield(app.BasicTab, 'numeric');
            app.AdvancedTab = uitab(app.TabGroup3);
            app.AdvancedTab.Title = 'Advanced';
            app.HeliumWeightsLabel = uilabel(app.AdvancedTab);
            app.HeliumWeightsLabel.Text = 'Helium Weights';
            app.AlphaLabel = uilabel(app.AdvancedTab);
            app.AlphaLabel.Text = 'Alpha';
            app.HeliumAlpha = uieditfield(app.AdvancedTab, 'numeric');
            app.BetaLabel = uilabel(app.AdvancedTab);
            app.BetaLabel.Text = 'Beta';
            app.HeliumBeta = uieditfield(app.AdvancedTab, 'numeric');
            app.GammaLabel = uilabel(app.AdvancedTab);
            app.GammaLabel.Text = 'Gamma';
            app.HeliumGamma = uieditfield(app.AdvancedTab, 'numeric');
            app.BallastWeightsLabel = uilabel(app.AdvancedTab);
            app.BallastWeightsLabel.Text = 'Ballast Weights';
            app.BetaLabel_2 = uilabel(app.AdvancedTab);
            app.BetaLabel_2.Text = 'Beta';
            app.BallastBeta = uieditfield(app.AdvancedTab, 'numeric');
            app.AlphaLabel_2 = uilabel(app.AdvancedTab);
            app.AlphaLabel_2.Text = 'Alpha';
            app.BallastAlpha = uieditfield(app.AdvancedTab, 'numeric');
            app.GammaLabel_2 = uilabel(app.AdvancedTab);
            app.GammaLabel_2.Text = 'Gamma';
            app.BallastGamma = uieditfield(app.AdvancedTab, 'numeric');
            app.SendMessageButton = uibutton(app.SendMessageTab, 'state');
            app.SendMessageButton.ValueChangedFcn = createCallbackFcn(app, @SendMessageButtonValueChanged, true);
            app.SendMessageButton.Text = 'Send Message';
            app.CurrentModePanel = uipanel(app.SendMessageTab);
            app.CurrentModePanel.Title = 'Current Mode';
            app.ManualModeLampLabel = uilabel(app.CurrentModePanel);
            app.ManualModeLampLabel.Text = 'Manual Mode';
            app.ManualModeLamp = uilamp(app.CurrentModePanel);
            app.AdvancedModeLampLabel = uilabel(app.CurrentModePanel);
            app.AdvancedModeLampLabel.Text = 'Advanced Mode';
            app.AdvancedModeLamp = uilamp(app.CurrentModePanel);
            app.BasicModeLampLabel = uilabel(app.CurrentModePanel);
            app.BasicModeLampLabel.Text = 'Basic Mode';
            app.BasicModeLamp = uilamp(app.CurrentModePanel);
            app.GoalPositionLabel = uilabel(app.SendMessageTab);
            app.GoalPositionLabel.Text = 'Goal Position';
            app.LatitudeLabel_2 = uilabel(app.SendMessageTab);
            app.LatitudeLabel_2.Text = 'Latitude';
            app.SetLatitude = uieditfield(app.SendMessageTab, 'numeric');
            app.SetLatitude.Value = 18;
            app.LongitudeLabel_2 = uilabel(app.SendMessageTab);
            app.LongitudeLabel_2.Text = 'Longitude';
            app.SetLongitude = uieditfield(app.SendMessageTab, 'numeric');
            app.SetLongitude.Value = -100;
            app.UpdateRateLabel_2 = uilabel(app.SendMessageTab);
            app.UpdateRateLabel_2.Text = 'Update Rate';
            app.SecondsLabel = uilabel(app.SendMessageTab);
            app.SecondsLabel.Text = 'Seconds';
            app.UpdateRate = uieditfield(app.SendMessageTab, 'numeric');
            app.SentMessageListTab = uitab(app.TabGroup2);
            app.SentMessageListTab.Title = 'Sent Message List';
            app.UITable = uitable(app.SentMessageListTab);
            app.UITable.ColumnName = {'Altitude'; 'Altitude Buffer'; 'Latitude'; 'Longitude'; 'Cutdown'; 'Update Rate'; 'Helium Alpha'; 'Helium Beta'; 'Helium Gamma'; 'Ballast Alpha'; 'Ballast Beta'; 'Ballast Gamma'; 'Helium Vent Time'; 'Helium Vent Increment'; 'Ballast Drop Increment'; 'Temperature Setpoint'; 'Temp P'; 'Temp I'; 'Temp D'; 'Control Mode'; 'Manual Adjust'; 'Manual Select'; 'Manual Amount'; 'Date'};
            app.UITable.RowName = {};
            app.ExportDataButton = uibutton(app.SentMessageListTab, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.ViewRecievedMessagesTab = uitab(app.TabGroup2);
            app.ViewRecievedMessagesTab.Title = 'View Recieved Messages';
            app.LatitudeLabel = uilabel(app.ViewRecievedMessagesTab);
            app.LatitudeLabel.Text = 'Latitude';
            app.Latitude = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Latitude.Editable = 'off';
            app.LongitudeLabel = uilabel(app.ViewRecievedMessagesTab);
            app.LongitudeLabel.Text = 'Longitude';
            app.Longitude = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Longitude.Editable = 'off';
            app.AltitudeLabel = uilabel(app.ViewRecievedMessagesTab);
            app.AltitudeLabel.Text = 'Altitude';
            app.Altitude = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Altitude.Editable = 'off';
            app.PositionLabel = uilabel(app.ViewRecievedMessagesTab);
            app.PositionLabel.Text = 'Position';
            app.ToViewDataSelectTimeReceivedDropDownLabel = uilabel(app.ViewRecievedMessagesTab);
            app.ToViewDataSelectTimeReceivedDropDownLabel.Text = 'To View Data Select Time Received';
            app.SelectTimeDropDown = uidropdown(app.ViewRecievedMessagesTab);
            app.SelectTimeDropDown.Items = {'00-00-2018 00:00:00'};
            app.SelectTimeDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectTimeDropDownValueChanged, true);
            app.SelectTimeDropDown.Value = '00-00-2018 00:00:00';
            app.XComponentLabel = uilabel(app.ViewRecievedMessagesTab);
            app.XComponentLabel.Text = 'X Component';
            app.VelocityX = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.VelocityX.Editable = 'off';
            app.YComponentLabel = uilabel(app.ViewRecievedMessagesTab);
            app.YComponentLabel.Text = 'Y Component';
            app.VelocityY = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.VelocityY.Editable = 'off';
            app.ZComponentLabel = uilabel(app.ViewRecievedMessagesTab);
            app.ZComponentLabel.Text = 'Z Component';
            app.VelocityZ = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.VelocityZ.Editable = 'off';
            app.VelocityLabel = uilabel(app.ViewRecievedMessagesTab);
            app.VelocityLabel.Text = 'Velocity';
            app.AccelerationLabel = uilabel(app.ViewRecievedMessagesTab);
            app.AccelerationLabel.Text = 'Acceleration';
            app.XComponentLabel_2 = uilabel(app.ViewRecievedMessagesTab);
            app.XComponentLabel_2.Text = 'X Component';
            app.AccX = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.AccX.Editable = 'off';
            app.YComponentLabel_2 = uilabel(app.ViewRecievedMessagesTab);
            app.YComponentLabel_2.Text = 'Y Component';
            app.AccY = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.AccY.Editable = 'off';
            app.ZComponentLabel_2 = uilabel(app.ViewRecievedMessagesTab);
            app.ZComponentLabel_2.Text = 'Z Component';
            app.AccZ = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.AccZ.Editable = 'off';
            app.TemperatureLabel = uilabel(app.ViewRecievedMessagesTab);
            app.TemperatureLabel.Text = 'Temperature';
            app.Temperature = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Temperature.Editable = 'off';
            app.PressureLabel = uilabel(app.ViewRecievedMessagesTab);
            app.PressureLabel.Text = 'Pressure';
            app.Pressure = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Pressure.Editable = 'off';
            app.AmbientConditionsLabel = uilabel(app.ViewRecievedMessagesTab);
            app.AmbientConditionsLabel.Text = 'Ambient Conditions';
            app.PayloadStateLabel = uilabel(app.ViewRecievedMessagesTab);
            app.PayloadStateLabel.Text = 'Payload State';
            app.HeliumVentedLabel = uilabel(app.ViewRecievedMessagesTab);
            app.HeliumVentedLabel.Text = 'Helium Vented';
            app.HeliumVented = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.HeliumVented.Editable = 'off';
            app.PayloadStatusLabel_2 = uilabel(app.ViewRecievedMessagesTab);
            app.PayloadStatusLabel_2.Text = 'Payload Status';
            app.BallastDropped = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.BallastDropped.Editable = 'off';
            app.BallastDroppedLabel = uilabel(app.ViewRecievedMessagesTab);
            app.BallastDroppedLabel.Text = 'Ballast Dropped';
            app.EmergencyLampLabel = uilabel(app.ViewRecievedMessagesTab);
            app.EmergencyLampLabel.Text = 'Emergency';
            app.EmergencyLamp = uilamp(app.ViewRecievedMessagesTab);
            app.StateEditFieldLabel = uilabel(app.ViewRecievedMessagesTab);
            app.StateEditFieldLabel.Text = 'State';
            app.StateEditField = uieditfield(app.ViewRecievedMessagesTab, 'text');
            app.StateEditField.Value = 'Testing';
            app.BatteryPercentageLabel = uilabel(app.ViewRecievedMessagesTab);
            app.BatteryPercentageLabel.Text = 'Battery Percentage';
            app.BatteryPercent = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.BatteryPercent.Editable = 'off';
            app.ControlModeEditFieldLabel = uilabel(app.ViewRecievedMessagesTab);
            app.ControlModeEditFieldLabel.Text = 'Control Mode';
            app.ControlModeEditField = uieditfield(app.ViewRecievedMessagesTab, 'text');
            app.ControlModeEditField.Value = 'Manual';
            app.RecievedDataGraphsTab = uitab(app.TabGroup2);
            app.RecievedDataGraphsTab.Title = 'Recieved Data Graphs';
            app.ExportDataButton_2 = uibutton(app.RecievedDataGraphsTab, 'push');
            app.ExportDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButton_2Pushed, true);
            app.ExportDataButton_2.Text = 'Export Data';
            app.DataTypeButtonGroup = uibuttongroup(app.RecievedDataGraphsTab);
            app.DataTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataTypeButtonGroupSelectionChanged, true);
            app.DataTypeButtonGroup.Title = 'Data Type';
            app.AltitudeButton = uiradiobutton(app.DataTypeButtonGroup);
            app.AltitudeButton.Text = 'Altitude';
            app.AltitudeButton.Value = true;
            app.LatitudeButton = uiradiobutton(app.DataTypeButtonGroup);
            app.LatitudeButton.Text = 'Latitude';
            app.TemperatureButton = uiradiobutton(app.DataTypeButtonGroup);
            app.TemperatureButton.Text = 'Temperature';
            app.PressureButton = uiradiobutton(app.DataTypeButtonGroup);
            app.PressureButton.Text = 'Pressure';
            app.BatteryButton = uiradiobutton(app.DataTypeButtonGroup);
            app.BatteryButton.Text = 'Battery';
            app.BallastDroppedButton = uiradiobutton(app.DataTypeButtonGroup);
            app.BallastDroppedButton.Text = 'Ballast Dropped';
            app.XAccelerationButton = uiradiobutton(app.DataTypeButtonGroup);
            app.XAccelerationButton.Text = 'X Acceleration';
            app.YAccelerationButton = uiradiobutton(app.DataTypeButtonGroup);
            app.YAccelerationButton.Text = 'Y Acceleration';
            app.ZAccelerationButton = uiradiobutton(app.DataTypeButtonGroup);
            app.ZAccelerationButton.Text = 'Z Acceleration';
            app.XVelocityButton = uiradiobutton(app.DataTypeButtonGroup);
            app.XVelocityButton.Text = 'X Velocity';
            app.YVelocityButton = uiradiobutton(app.DataTypeButtonGroup);
            app.YVelocityButton.Text = 'Y Velocity';
            app.ZVelocityButton = uiradiobutton(app.DataTypeButtonGroup);
            app.ZVelocityButton.Text = 'Z Velocity';
            app.HeliumVentedButton = uiradiobutton(app.DataTypeButtonGroup);
            app.HeliumVentedButton.Text = 'Helium Vented';
            app.LongitudeButton = uiradiobutton(app.DataTypeButtonGroup);
            app.LongitudeButton.Text = 'Longitude';
            app.UIAxes = uiaxes(app.RecievedDataGraphsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.AcknowledgeButton = uibutton(app.UIFigure, 'push');
            app.AcknowledgeButton.ButtonPushedFcn = createCallbackFcn(app, @AcknowledgeButtonPushed, true);
            app.AcknowledgeButton.Text = 'Acknowledge';
            app.NoNewMessagesLampLabel = uilabel(app.UIFigure);
            app.NoNewMessagesLampLabel.Text = 'No New Messages';
            app.NewMessagesLamp = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = guiHighBall2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end